classdef TargetWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetWindowFig  matlab.ui.Figure
        DirectionAxes    matlab.ui.control.UIAxes
        GaugeAxesL       matlab.ui.control.UIAxes
        GaugeAxesR       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        num_gauges = 0                      % 0: no gauges 1: single gauge 2: both gauges
        % gauge parameters
        gauge_limits = [0 100]              % 
        % gauge params
        gauge_width = 50                    % pixels
        gauge_height
        % gauge patch objects
        gLPO                                % patch object handle to Left gauge
        gRPO                                % patch object handle to Right gauge
        gauge_vertices                      % 
        % pyramid patch object
        FBPyPO                              % patch object handle to feedback pyramid
        TPyPO                               % patch object handle to target pyramid
        TTracePO                            % patch object handle to target trace
        % pyramid parameters        
        py_vertices                         % 
        py_faces                            %  
        
        % 
        
        
    end
    
    properties (Access = private)
        
        
        % gauge defaults        
        gauge_bgcolor = 'w'                 % gauge background color
        gauge_edgecolor = 'k'               % gauge edge color
        gauge_fillcolor = 'r'               % gauge default fill color
        
        % pyramid defaults
        basecolor = [0 0.45 0.75]           % dark blue
        sidecolor = [0.9 0.7 0.9]           % yellow
        designcolor = [0.3 0.75 0.9]        % light blue
        neckcolor = [0 0.5 0.7]             % dark blue
        FBTransparency = 0.8                % FB Pyramid patch object FaceAlpha transparency value [0 1] (0 = transparent)
        TargetTransparency = 0.6            % Target Pyramid patch object FaceAlpha transparency value [0 1] (0 = transparent)
        py_width0 = 25                      % pyramid base width
        py_ht_ratio = 2                     % base ht = py_ht_ratio * py_width0
        py_nose_ratio = 3                   % pyramid nose length = py_nose_ratio * py_width0
        
        % DirectionAxes
        AxLim = [-100 100;...               % DirectionAxes limits (x,y,z) by (min max)
                 -100 100;...
                 -100 100]
        XAxLim = [-300 300]                 % actual X axis limits so nose doesn't get clipped
    end
    
    methods (Access = public)
        
        function updateGaugeValue(app, newval)

            % clip and scale value                   
            newval = vecmath.clip(newval, app.gauge_limits(1) + 0.01, app.gauge_limits(2));
            %newval = vecmath.changeScale(newval, app.gauge_limits, [0 app.gauge_height]);           

            % change Vertices property of gauge patch to update height
            if ~isempty(app.gRPO)                
                app.gRPO.Vertices(3:4,2) = [newval;newval];                
            end
            if ~isempty(app.gLPO)                
                app.gLPO.Vertices(3:4,2) = [newval;newval];                
            end
        end
    
        function changeGaugeFillColor(app, color)
            % color: color 
            if ~isempty(app.gRPO)            
                app.gRPO.FaceColor = color;
            end
            if ~isempty(app.gLPO)            
                app.gLPO.FaceColor = color;
            end            
        end
    
        function changeGaugeLimits(app, val_lims)
            % value_limits: [value_min value_max]
            app.gauge_limits = val_lims;
            
            % change y-axis limits
            if ~isempty(app.gRPO)
                app.GaugeAxesR.YLim = val_lims;
            end
            if ~isempty(app.gLPO)
                app.GaugeAxesL.YLim = val_lims;
            end       
            
        end
    
        function axlim = getAxLimits(app)
            % DirectionAxes x, y, and z limits
            % axlim: (3x2) (x y z) by (min max)
            
            axlim = app.AxLim;
            
        end
    
        function setAxLimits(app, axlim)
            % DirectionAxes x, y, and z limits
            % axlim: (3x2) (x y z) by (min max)
           
            app.DirectionAxes.XLim = axlim(1,:);
            app.DirectionAxes.YLim = axlim(2,:);
            app.DirectionAxes.ZLim = axlim(3,:);
            
            app.AxLim = axlim;

        end
    
        function changeCameraPosition(app, viewpoint)
            
            switch viewpoint
                case {'back',"back",0}
                    app.DirectionAxes.CameraPosition = [-200, 0, 0];
                case {'mirror',"mirror",1}
                    app.DirectionAxes.CameraPosition = [200, 0, 0];
                otherwise
                    warning('Invalid input, options are back and mirror')
            end
            
        end
    
        function pydims = getPyramidDims(app)
            % pydims: [nose length, base height, base width] (x, y, z) 
            
            w = app.py_width0;                    % (z) pyramid base width                  
            ht = app.py_ht_ratio * w;             % (y) base height
            d = app.py_nose_ratio * w;            % (x) nose length
            
            pydims = [d, ht, w]; 
            
        end
    
        function setPyramidDims(app, pydims)
            % pydims: [nose length, base height, base width]
            % still need to recreate any existing pyramids to actually update
            
            app.py_width0 = pydims(3);            % (z) pyramid base width                  
            app.py_ht_ratio = pydims(2)/w;        % (y) base height
            app.py_nose_ratio = pydims(1)/w;      % (x) nose length            
            
        end
    
        function createFBPyramid(app)
            [app.FBPyPO, app.py_vertices] = app.initializePyramid(); 
            app.changeFBPyramidTransparency(app.FBTransparency);
        end
        
        function createTargetPyramid(app)
            app.TPyPO = app.initializePyramid();   
            app.changeTPyramidTransparency(app.TargetTransparency);
        end
    
        function targetPyramidVisible(app, onoff)
            if ~isempty(app.TPyPO)
                app.TPyPO.Visible = onoff;
            end
        end
    
        function FBPyramidVisible(app, onoff)
            if ~isempty(app.FBPyPO)
                app.FBPyPO.Visible = onoff;
            end
        end
    
        function changeTPyramidTransparency(app, alpha)
            if ~isempty(app.TPyPO)
                app.TPyPO.FaceAlpha = alpha;
            end
            app.TargetTransparency = alpha;
        end
    
        function changeFBPyramidTransparency(app, alpha)
            if ~isempty(app.FBPyPO)
                app.FBPyPO.FaceAlpha = alpha;
            end
            app.FBTransparency = alpha;
        end
    
        function updateFBPyramid(app, Tmat)
            
            % transform these points from original vertices
            vp = app.py_vertices(1:8,:);            
            
            % update patch object vertices
            app.FBPyPO.Vertices(1:8,:) = app.transformVertices(vp, Tmat);           
            
        end
    
        function updateTargetPyramid(app, Tmat)
            
            % transform these points from original vertices
            vp = app.py_vertices(1:8,:);            
            
            % update patch object vertices
            app.TPyPO.Vertices(1:8,:) = app.transformVertices(vp, Tmat);           
            
        end
    
    end
    
    methods (Access = private)
        
        function v = changeFillHeight(~, v, ht)
            % based on rectangle vertices created in
            % createRectangle(x0,y0,xm,ym). Changing only the height of the
            % rectangle. 
            v(3:4,2) = [ht; ht];
        end
    
        function [po, v] = createGaugeFillPO(~, ax, fill_color)
            
            % Coordinates
            x0 = 0;
            y0 = 0;
            xm = ax.Position(3);
            ym = 1; % default
            
            % Vertices (x, y)
            v = [  x0      y0; ... % 1 % lower left
                   xm      y0; ... % 2 % lower right
                   xm      ym; ... % 3 % upper right
                   x0      ym];    % 4 % upper left
            
            % Single Face
            f = [ 1 2 3 4 ];       % face                      
            
            po = patch('Parent', ax...
                ,'Vertices', v, 'Faces', f...
                ,'FaceColor', fill_color, 'EdgeColor', 'none');            
            
        end

    
        function [po, v] = initializePyramid(app)
            
            % get y axis limits
            yl = app.DirectionAxes.YLim;
            zl = app.DirectionAxes.ZLim;
            
            % sizes of zero pyramid
            px = app.py_nose_ratio * app.py_width0;     % nose length
            py = app.py_ht_ratio * app.py_width0;       % head height
            pz = app.py_width0;                         % head width
            nz = app.py_width0 / 4;                     % neck width 
            ty =  yl(1) + ((yl(2) - yl(1))/2) / 4;      % torso height
            tz = ((zl(2) - zl(1))/2);                   % torso width


            %pyramid vertices (8) (need to transpose for hom, unhom, and computations)
            v =     [    0    py    -pz/2  ;... %1 % head pyramid
                         0    py     pz/2  ;... %2
                         0    0      pz/2  ;... %3
                         0    0     -pz/2  ;... %4
                         px   py/2    0    ;... %5
                         0    py/2    0    ;... %6
                         0    0     -nz    ;... %7 % neck
                         0    0      nz    ;... %8
                         0    ty     nz    ;... %9 % do not transform from here on
                         0    ty    -nz    ;... %10
                         0    ty    -tz/2  ;... %11 %torso
                         0    ty     tz/2  ;... %12
                         0    yl(1)  tz/2  ;... %13
                         0    yl(1) -tz/2 ];... %14                
                         
        
            % pyramid faces (8)
            f = [   1  6  2  1  ; ...% base top
                    2  6  3  2  ; ...% base right
                    3  6  4  3  ; ...% base bottom
                    4  6  1  4  ; ...% base left
                    1  5  2  1  ; ...% side top
                    2  5  3  2  ; ...% side right
                    3  5  4  3  ; ...% side bottom
                    4  5  1  4  ; ...% side left 
                    7  8  9  10 ; ...% neck
                   11 12 13  14 ];...% torso
            
            c = [ repmat(app.designcolor, 1,1); ... % design color
                  repmat(app.basecolor,   3,1); ... % base color
                  repmat(app.sidecolor,   4,1); ... % side color
                  repmat(app.neckcolor,   2,1) ];   % neck and torso             
            
            % create patch 
            po = patch('Parent', app.DirectionAxes...
                ,'Vertices', v, 'Faces', f...
                ,'FaceColor', 'flat', 'FaceVertexCData', c); 
            
        end
    
        function v = transformVertices(~, v0, Tmat)
            % v0: (n x 3) vertices of shape in (x y z) coords 
            % Tmat : (4x4) homogenous transformation matrix 
            % v : transformed coordinates

            v = vecmath.unhom(Tmat*vecmath.hom(v0'))';

        end
    
    
        function updatePyramid(app, po, Tmat)

            % transform these points from original vertices
            vp = app.py_vertices(1:8,:);            
            
            % update patch object vertices
            po.Vertices(1:8,:) = app.transformVertices(vp, Tmat);           
            
        end


        function initializeAxes(app, nGauges)
            
            % get current figure dimensions
            figdim = app.TargetWindowFig.Position(3:4);
            
            switch nGauges
                
                case 0 % no gauges
                    
                    % make gauge axes invisible
                    app.GaugeAxesL.Visible = 'off';
                    app.GaugeAxesR.Visible = 'off';                    
                    
                    % maximize DirectionAxes to fill full figure
                    app.DirectionAxes.Position = [0 0 figdim];
                    % make sure Data Aspect Ratio is still equal
                    app.DirectionAxes.DataAspectRatio = [1 1 1];                    
                    
                
                case 1 % single gauge (right side)
                    % make gauge axes invisible
                    app.GaugeAxesL.Visible = 'off';
                    app.GaugeAxesR.Visible = 'on';
                    
                    % DirectionAxes dimensions
                    daxpos = [0 0];
                    daxdim = [figdim(1)-app.gauge_width, figdim(2)];
                    app.DirectionAxes.Position = [daxpos daxdim];
                    % make sure Data Aspect Ratio is still equal
                    app.DirectionAxes.DataAspectRatio = [1 1 1]; 
                    
                    % GaugeAxesR dimensions
                    gaxdim = [daxpos(1)+daxdim(1), figdim(2)];
                    gaxpos = [daxdim(1) 0]; %lower left corner
                    app.GaugeAxesR.Position = [gaxpos gaxdim];
                    
                    
                    % make patch objects
                    [app.gRPO, app.gauge_vertices] = app.createGaugeFillPO(app.GaugeAxesR, app.gauge_fillcolor);
                
                case 2 % both gauges (left and right sides)
                    % make gauge axes invisible
                    app.GaugeAxesL.Visible = 'on';
                    app.GaugeAxesR.Visible = 'on';                   
                    
                    % Gauge dimensions
                    gaxdim = [app.gauge_width, figdim(2)];
                    
                    % GaugeAxesL                 
                    gLaxpos = [0 0]; %lower left corner
                    app.GaugeAxesR.Position = [gLaxpos gaxdim];
                    
                    % DirectionAxes
                    daxdim = [figdim(1)-2*app.gauge_width, figdim(2)];
                    daxpos = [gLaxpos(1)+gaxdim(1), 0]; % to right of L gauge
                    app.DirectionAxes.Position = [daxpos daxdim];
                    % make sure Data Aspect Ratio is still equal
                    app.DirectionAxes.DataAspectRatio = [1 1 1]; 
                    
                    % GaugeAxesR
                    gRaxpos = [daxpos(1)+daxdim(1), 0]; % to right of DirectionAxes
                    app.GaugeAxesR.Position = [gRaxpos gaxdim];
                    
                    % create patch objects
                    [app.gRPO, app.gauge_vertices] = app.createGaugeFillPO(app.GaugeAxesR, app.gauge_fillcolor);
                    app.gLPO = app.createGaugeFillPO(app.GaugeAxesL, app.gauge_fillcolor);
                
                otherwise
                    error('Can not have more than 2 gauges.')
                    
            end
            
            % for convenience
            app.gauge_width = app.GaugeAxesR.Position(3);
            app.gauge_height = app.GaugeAxesR.Position(4);
            
            % set axes limits
            app.DirectionAxes.XLim = app.XAxLim;
            app.DirectionAxes.YLim = app.AxLim(2,:);
            app.DirectionAxes.ZLim = app.AxLim(3,:);   
            
            % gauge axes limits
            app.GaugeAxesL.XLim = [0 app.gauge_width];
            app.GaugeAxesR.XLim = [0 app.gauge_width];
            app.GaugeAxesL.YLim = app.gauge_limits;
            app.GaugeAxesR.YLim = app.gauge_limits;
           
            
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ngauges)

            
            if nargin > 1
                app.num_gauges = ngauges;
            end
            
            % initialize number of gauges and size of axes to maximize space in figure
            app.initializeAxes(app.num_gauges);           
            
            
        end

        % Close request function: TargetWindowFig
        function TargetWindowFigCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetWindowFig = uifigure;
            app.TargetWindowFig.Name = 'UI Figure';
            app.TargetWindowFig.CloseRequestFcn = createCallbackFcn(app, @TargetWindowFigCloseRequest, true);
            app.DirectionAxes = uiaxes(app.TargetWindowFig);
            app.DirectionAxes.XLim = [-300 300];
            app.DirectionAxes.YLim = [-100 100];
            app.DirectionAxes.ZLim = [-100 100];
            app.DirectionAxes.XAxisLocation = 'origin';
            app.DirectionAxes.XTick = [];
            app.DirectionAxes.XTickLabel = {};
            app.DirectionAxes.YAxisLocation = 'origin';
            app.DirectionAxes.YTick = [];
            app.DirectionAxes.YTickLabel = {};
            app.DirectionAxes.ZTick = [];
            app.DirectionAxes.ZTickLabel = '';
            app.GaugeAxesL = uiaxes(app.TargetWindowFig);
            app.GaugeAxesL.XLim = [0 100];
            app.GaugeAxesL.YLim = [0 30];
            app.GaugeAxesL.XTick = [0 100];
            app.GaugeAxesL.XTickLabel = {'0'; '100'};
            app.GaugeAxesL.YTick = [0 100];
            app.GaugeAxesL.YTickLabel = {'-'; '100'};
            app.GaugeAxesR = uiaxes(app.TargetWindowFig);
            app.GaugeAxesR.XLim = [0 100];
            app.GaugeAxesR.YLim = [0 30];
            app.GaugeAxesR.XTick = [0 100];
            app.GaugeAxesR.XTickLabel = {'0'; '100'};
            app.GaugeAxesR.YTick = [0 100];
            app.GaugeAxesR.YTickLabel = {'-'; '100'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TargetWindow(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetWindowFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetWindowFig)
        end
    end
end