classdef tDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetDAQUI                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        DAQConfigurationPanel           matlab.ui.container.Panel
        DAQDeviceDropDownLabel          matlab.ui.control.Label
        DAQDeviceDropDown               matlab.ui.control.DropDown
        ScanRateLabel                   matlab.ui.control.Label
        scanRateField                   matlab.ui.control.NumericEditField
        SampleTimeLabel                 matlab.ui.control.Label
        sampleTimeField                 matlab.ui.control.NumericEditField
        InitializeDAQButton             matlab.ui.control.Button
        SubjectInformationPanel         matlab.ui.container.Panel
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        ParticipantIDField              matlab.ui.control.EditField
        ProtocolEditFieldLabel          matlab.ui.control.Label
        ProtocolField                   matlab.ui.control.EditField
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesField                      matlab.ui.control.TextArea
        EMGOptionsPanel                 matlab.ui.container.Panel
        CollectEMGCheckBox              matlab.ui.control.CheckBox
        ofEMGsSpinnerLabel              matlab.ui.control.Label
        nEMGSpinner                     matlab.ui.control.Spinner
        FileOptionsPanel                matlab.ui.container.Panel
        CurrentDirectoryButton          matlab.ui.control.Button
        CurrentDirectoryTextArea        matlab.ui.control.TextArea
        basefilenameLabel               matlab.ui.control.Label
        basefilenameEditField           matlab.ui.control.EditField
        SaveLoadPanel                   matlab.ui.container.Panel
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        TransformTab                    matlab.ui.container.Tab
        BonyLandmarkTableLabel          matlab.ui.control.Label
        HeadFrameTable                  matlab.ui.control.Table
        XForwardYVerticalupZRightLabel  matlab.ui.control.Label
        WrenchFrameDropDownLabel        matlab.ui.control.Label
        WrenchFrameDropDown             matlab.ui.control.DropDown
        SetTargetTab                    matlab.ui.container.Tab
        TargetSettingsPanel             matlab.ui.container.Panel
        TargetDirectionLabel            matlab.ui.control.Label
        TargetTable                     matlab.ui.control.Table
        OpenTargetWindowButton          matlab.ui.control.StateButton
        ZeroLoadCellButton              matlab.ui.control.Button
        WrenchRangesPanel               matlab.ui.container.Panel
        maxFSpinnerLabel                matlab.ui.control.Label
        maxFSpinner                     matlab.ui.control.Spinner
        maxMSpinnerLabel                matlab.ui.control.Label
        maxMSpinner                     matlab.ui.control.Spinner
        lbLabel                         matlab.ui.control.Label
        lbinLabel                       matlab.ui.control.Label
        TargetModeDropDownLabel         matlab.ui.control.Label
        TargetModeDropDown              matlab.ui.control.DropDown
        ActiveComponentsPanel           matlab.ui.container.Panel
        FxCheckBox                      matlab.ui.control.CheckBox
        FyCheckBox                      matlab.ui.control.CheckBox
        FzCheckBox                      matlab.ui.control.CheckBox
        MxCheckBox                      matlab.ui.control.CheckBox
        MyCheckBox                      matlab.ui.control.CheckBox
        MzCheckBox                      matlab.ui.control.CheckBox
        TargetWindowOptionsPanel        matlab.ui.container.Panel
        ShowTargetCheckBox              matlab.ui.control.CheckBox
        ShowFeedbackCheckBox            matlab.ui.control.CheckBox
        ViewDropDownLabel               matlab.ui.control.Label
        ViewDropDown                    matlab.ui.control.DropDown
        TargetTransparencySpinnerLabel  matlab.ui.control.Label
        TargetTransparencySpinner       matlab.ui.control.Spinner
        FBTransparencySpinnerLabel      matlab.ui.control.Label
        FBTransparencySpinner           matlab.ui.control.Spinner
        DirectionMatchingErrorPanel     matlab.ui.container.Panel
        MatchErrorLabel                 matlab.ui.control.Label
        DirMatchErrorEditField          matlab.ui.control.NumericEditField
        ErrorModeDropDownLabel          matlab.ui.control.Label
        ErrorModeDropDown               matlab.ui.control.DropDown
        WrenchPlotsTab                  matlab.ui.container.Tab
        WrenchAxes1                     matlab.ui.control.UIAxes
        WrenchAxes2                     matlab.ui.control.UIAxes
        WrenchAxes3                     matlab.ui.control.UIAxes
        WrenchAxes4                     matlab.ui.control.UIAxes
        WrenchAxes5                     matlab.ui.control.UIAxes
        WrenchAxes6                     matlab.ui.control.UIAxes
        EMGPlotsTab                     matlab.ui.container.Tab
        EMGAxes1                        matlab.ui.control.UIAxes
        EMGAxes2                        matlab.ui.control.UIAxes
        EMGAxes3                        matlab.ui.control.UIAxes
        EMGAxes4                        matlab.ui.control.UIAxes
        EMGAxes5                        matlab.ui.control.UIAxes
        EMGAxes6                        matlab.ui.control.UIAxes
        GaugesTab                       matlab.ui.container.Tab
        WMagGaugeLabel                  matlab.ui.control.Label
        WMagGauge                       matlab.ui.control.LinearGauge
        WMagEditField                   matlab.ui.control.NumericEditField
        ForcesPanel                     matlab.ui.container.Panel
        FMagEditField                   matlab.ui.control.NumericEditField
        FxMagEditField                  matlab.ui.control.NumericEditField
        FyMagEditField                  matlab.ui.control.NumericEditField
        FzMagEditField                  matlab.ui.control.NumericEditField
        FMagGauge_2Label                matlab.ui.control.Label
        FMagGauge                       matlab.ui.control.LinearGauge
        FxGaugeLabel                    matlab.ui.control.Label
        FxGauge                         matlab.ui.control.LinearGauge
        FzGaugeLabel                    matlab.ui.control.Label
        FzGauge                         matlab.ui.control.LinearGauge
        ForwardLabel                    matlab.ui.control.Label
        BackwardLabel                   matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        UpLabel                         matlab.ui.control.Label
        DownLabel                       matlab.ui.control.Label
        FyGaugeLabel                    matlab.ui.control.Label
        FyGauge                         matlab.ui.control.LinearGauge
        LeftLabel                       matlab.ui.control.Label
        MomentsPanel                    matlab.ui.container.Panel
        MxMagEditField                  matlab.ui.control.NumericEditField
        MyMagEditField                  matlab.ui.control.NumericEditField
        MzMagEditField                  matlab.ui.control.NumericEditField
        MMagEditField                   matlab.ui.control.NumericEditField
        MMagGaugeLabel                  matlab.ui.control.Label
        MMagGauge                       matlab.ui.control.LinearGauge
        MzGaugeLabel                    matlab.ui.control.Label
        MzGauge                         matlab.ui.control.LinearGauge
        MxGaugeLabel                    matlab.ui.control.Label
        MxGauge                         matlab.ui.control.LinearGauge
        MyGaugeLabel                    matlab.ui.control.Label
        MyGauge                         matlab.ui.control.LinearGauge
        TurnLeftLabel                   matlab.ui.control.Label
        TurnRightLabel                  matlab.ui.control.Label
        SBLeftLabel                     matlab.ui.control.Label
        SBRightLabel                    matlab.ui.control.Label
        LookUpLabel                     matlab.ui.control.Label
        LookDownLabel                   matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialNumberField                matlab.ui.control.NumericEditField
        StatusLabel                     matlab.ui.control.Label
        AcquireDataButton               matlab.ui.control.Button
    end

    % FRAMES
    % Head body coordinate frame is oriented so that: 
    %       x points forward towards the nose, 
    %       y is vertical up
    %       z is to the right
    % 
    % World frame is at the center of the load cell oriented: 
    %       x points forward (LC z)
    %       y is vertical (LC Rz(-45deg))
    %       z points to the subjects right
    % 
    % 
    
    properties (Access = private)         
        
        % Target Window Figure Handles
        FigH                                            % handle to TargetWindow object        
                     
        % Target Window Modes
        TargetMode                                      % Target visual mode from dropdown (1: Channel 2: Orientation and Mag 3: ...)
        FBFrameMode                                     % Frame of FB Wrench from dropdown (1: Head 2: LoadCell 3: World)
        
        ErrorMode                                       % 1: Angle, 2: Norm, 3: Individual
        error_window = deg2rad(5)                       % Error allowed to match direction (radians)
        
        % Target Setting Values
        TargetValues = zeros(6,1);                      % 1x6 array of target direction [fx fy fz mx my mz]    
              
        % Range for visual feedback
        VisLim                                          % (6x2) (x,y,z,tx,ty,tz) x (min max) set with axlim2vislim()    
        
        % Wrench Range
        WRanges = [ -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30 ]                            % (6x2) (Fx,Fy,Fz,Mx,My,Mz) by (min max) in N or Nm
                
        % 
        fscale                                          % 
        mscale                                          % 
        
        py_scale_range = [1.75 0.25]                    % x-axis linear scaling of pyramid
        py_rot_range = deg2rad(45)                      % max rotation in radians
        
        % 
        
        
        % DAQ Configuration 
        DevicesInfo                                     % array of device information 
        daqDevice = 'Dev1'                              % default device name 
        sRate = 500                                     % default sampling rate (Hz)
        sTime = 10                                      % default sampling length (sec)
        
        % 
        DAQSession = []                                 % daq session from s = daqinit()
        
        % Listener Handle
        DataAvailableLH                                 % listener handle for DAQ session 'DataAvailable' event
        
        % Transformation Matrices 
        CalMatrix = eye(6)                              % Load cell calibration matrix 
        LC2Head = eye(6)                                % Transform matrix from Load Cell
        LCZeroOffset = zeros(6,1)                       % load cell values from last time zero button pressed 
        
        FBCompsMat = eye(6)                             % 6x6 diagonal mat from active components checkboxes
        
        % Line Handles 
        WrenchLH = []                                   % array of Line handles
        EMGLH = []                                      % array of Line handles 
        
        % Axes Handles
        WrenchAxes = []                                 % array of uiAxis handles 
        EMGAxes = []                                    % array of uiAxis handles 
        
        % Buffers
        TimestampsFIFOBuffer = []                       % Timestamps FIFO buffer of latest N sec
        RawDataFIFOBuffer = []                          % Raw voltage data from DAQ 
        WrenchFIFOBuffer = []                           % Wrench Data FIFO buffer (ch x n)
        EMGFIFOBuffer = []                              % EMG DATA FIFO buffer (ch x n)
        FIFOMaxSize = 1E+5                              % Max allowed buffer size
        
        % Configuration Information 
        Date = []                                       % saved date
        datadir = cd                                    % Description
        subjID = []                                     % 
        Protocol = []                                   % 
        Notes = []                                      %
        
        WrenchChannels = 0:5                            % daq channels for load cell inputs
        EMGChannelsAvail = 6:11                         % daq channels available for EMG inputs
        EMGChannels = []                                % daq channels for EMG inputs
        WrenchChannelNames = {'Fx','Fy','Fz','Mx','My','Mz'}
        EMGChannelNames = {'EMG1','EMG2','EMG3','EMG4','EMG5','EMG6'}
        
        % filepaths
        CalMatPath = jr3_loadcell.cal_mat                  % Calibration Matrix 
        
        % settings
        collectEMG = 0                                  % boolean: 0 = don't collect EMG; 1 = collect EMG
        
        itrial = 1                                      % 
        filename = 'data_trial'                         % 
        
        logdata = 0                                     % boolean: 0: don't save data; 1: save data to file
        starttime = []                                  % timestamp of first logged entry, set to empty if not logging
        TempFilename
        TempFile                                        % Temporary 
        TotalDataSaved                                  % Description

    end
    
    
    methods (Access = private)               

        
        function axlim2vislim(app)
            % use this to set VisLim property for proper scaling for Target Window
            % FigH must exist already (ie TargetWindow class has been created)
            
            if ~isempty(app.FigH)
                
                % TargetWindow axis limits
                axlim = app.FigH.getAxLimits(); 
                % dimensions of pyramid in (x,y,z) at 0 
                pydims = app.FigH.getPyramidDims();                             
                
                % set VisLim property for scaling Force and Moment to Visual Feedback 
                limsetting = 1;                
                switch limsetting
                    
                    case 0 % conservative. will not go outside bounds
                        % size of max dimension of pyramid
                        maxpy = max(max(app.py_scale_range) * pydims(2:3)); 
                        %set app property
                        app.VisLim =   [    axlim(1,1) + maxpy,         axlim(1,2) - maxpy  ;    % fx
                                            axlim(2,1) + maxpy,         axlim(2,2) - maxpy  ;    % fy
                                            axlim(3,1) + maxpy,         axlim(3,2) - maxpy  ;    % fz
                                            -app.py_rot_range,          app.py_rot_range    ;    % mx
                                            -app.py_rot_range,          app.py_rot_range    ;    % my
                                            -app.py_rot_range,          app.py_rot_range    ];   % mz
                                        
                    case 1 % in between. 
                        % size of max dimension of pyramid
                        maxpy = max(max(pydims(2:3)));
                        %set app property
                        app.VisLim =   [    axlim(1,1) + maxpy,         axlim(1,2) - maxpy  ;    % fx
                                            axlim(2,1) + maxpy,         axlim(2,2) - maxpy  ;    % fy
                                            axlim(3,1) + maxpy,         axlim(3,2) - maxpy  ;    % fz
                                            -app.py_rot_range,          app.py_rot_range    ;    % mx
                                            -app.py_rot_range,          app.py_rot_range    ;    % my
                                            -app.py_rot_range,          app.py_rot_range    ];   % mz
                        
                                        
                    case 2 % less conservative. sometimes goes outside bounds
                                
                        % set app property
                        app.VisLim =   [    axlim(1,1),                 axlim(1,2)          ;    % fx
                                            axlim(2,1),                 axlim(2,2)          ;    % fy
                                            axlim(3,1),                 axlim(3,2)          ;    % fz
                                            -app.py_rot_range,          app.py_rot_range    ;    % mx
                                            -app.py_rot_range,          app.py_rot_range    ;    % my
                                            -app.py_rot_range,          app.py_rot_range    ];   % mz
                end
                                
            else
                warning('VisLim property not set because app.FigH is empty')                
            end          
            
        end
    
        function updateGaugeLims(app)
        % Update the Gauge Indicator limits [min max] in the Gauges Tab
            
            % Compute maximum magnitude of Force and Moment Components
            maxf = vecmath.maxnorm(app.WRanges(1:3,:));
            maxm = vecmath.maxnorm(app.WRanges(4:6,:));
           
            % Set Gauge Object limits 
            app.WMagGauge.Limits = [0 1];
            app.FMagGauge.Limits = [0 maxf];
            app.MMagGauge.Limits = [0 maxm];            
            
        end
        
        function changeGaugeColorLims(app) 
        % Change shaded area indicating target for gauges in Gauges tab based on Target Values
            
            % Break Target Values into unit direction components 
            [fu, ~, mu, ~, ~, ~, ~] = app.wrench2components(app.FBCompsMat*app.TargetValues);
            
            % Target indicator color 
            color_red = [1 0 0];
            
            % calculate target limits within range [-1 1]
            del = 0.1; % range size
            mlims = vecmath.clip(mu + [-del/2 del/2], -1, 1);
            flims = vecmath.clip(fu + [-del/2 del/2], -1, 1);
            
            % Set Gauge Object Scale Color Properties
            app.MxGauge.ScaleColors = color_red;
            app.MxGauge.ScaleColorLimits = mlims(1,:);
            app.MyGauge.ScaleColors = color_red;
            app.MyGauge.ScaleColorLimits = mlims(2,:);
            app.MzGauge.ScaleColors = color_red;
            app.MzGauge.ScaleColorLimits = mlims(3,:);
            app.FxGauge.ScaleColors = color_red;
            app.FxGauge.ScaleColorLimits = flims(1,:);
            app.FyGauge.ScaleColors = color_red;
            app.FyGauge.ScaleColorLimits = flims(2,:);
            app.FzGauge.ScaleColors = color_red;
            app.FzGauge.ScaleColorLimits = flims(3,:);
            
        end
    
        function updateGauges(app, wrench)
        % Update all gauge indicators in Gauges tab based on current measured wrench 
            
            % Break wrench into components
            [fu, fmag, mu, mmag, wmagn, ~, ~] = app.wrench2components(wrench);
            
            % Wrench Magnitude Indicators
            app.WMagEditField.Value = wmagn;
            app.WMagGauge.Value = wmagn;
            
            % Force Direction and Magnitude Indicators
            app.FMagEditField.Value = fmag;
            app.FMagGauge.Value = fmag;            
            app.FxMagEditField.Value = fu(1);
            app.FxGauge.Value = fu(1);
            app.FyMagEditField.Value = fu(2);
            app.FyGauge.Value = fu(2);
            app.FzMagEditField.Value = fu(3);
            app.FzGauge.Value = fu(3);
            
            % Moment Direction and Magnitude Indicators
            app.MMagEditField.Value = mmag;
            app.MMagGauge.Value = mmag;
            app.MxMagEditField.Value = mu(1);
            app.MxGauge.Value = mu(1);
            app.MyMagEditField.Value = mu(2);
            app.MyGauge.Value = mu(2);
            app.MzMagEditField.Value = mu(3);
            app.MzGauge.Value = mu(3);           
            
        end
    
        function [fu, fmag, mu, mmag, wmagn, fmagn, mmagn] = wrench2components(app, wrench)            
           
            % unit vectors and magnitudes
            [fu, fmag] = vecmath.unitvec(wrench(1:3));
            [mu, mmag] = vecmath.unitvec(wrench(4:6));
            
            % Max magnitude possible based on WRange
            fmaxmag = vecmath.maxnorm(app.WRanges(1:3,:));
            mmaxmag = vecmath.maxnorm(app.WRanges(4:6,:));
            
            % normalized magnitudes
            fmagn = fmag/fmaxmag;
            mmagn = mmag/mmaxmag;
            wmagn = (fmagn + mmagn)/2;           
            
        end 
    
        
        function [Tmat, wmagn] = wrench2Tmat(app, wrench)
        % 
        % Inputs: wrench: (6x1) (fx,fy,fz,mx,my,mz)' 
        % Outputs:  Tmat: (4x4) Transform matrix in vis coordinates
        %           wmagn: (scalar) normalized wrench magnitude [0 1]
        
            % Only consider active components of vector
            wrench = app.FBCompsMat*wrench;           
            % Break down wrench into components
            [fu, fmag, mu, mmag, wmagn, fmagn, mmagn] = app.wrench2components(wrench); 
            
            % Scale magnitudes based on target view mode
            switch app.TargetMode
                
                case {1,'1'} % match                
                    % pass through wrench components from above  
                    
                case {2,'2'} % dir and mag
                   
                    if mmag ~= 0
                       
                       % ratio of force:moment 
                       fmr = fmag/mmag;                       
                       
                       if fmagn > mmagn % limited by force                            
                            fmag = app.WRanges(1,2)*(2/3); % change to a fmax property
                            mmag = fmag/fmr;
                        else % mmagn >= fmagn % limited by moment (includes fmag == 0) 
                            mmag = app.WRanges(4,2)*(2/3); % change to an mmax property
                            fmag = mmag*fmr;
                       end
                       
                    else % mmag == 0 
                       fmag = app.WRanges(1,2)*(2/3); % change to a fmax property
                    end 

                otherwise
                    error('invalid TargetModeDropDown.Value')
            end    
            
            % Transform to VisLim Coords
            fvec_vis = vecmath.changeScale(fmag*fu, app.WRanges(1:3,:), app.VisLim(1:3,:));
            mvec_vis = vecmath.changeScale(mmag*mu, app.WRanges(4:6,:), app.VisLim(4:6,:));
            [mu_vis, mmag_vis] = vecmath.unitvec(mvec_vis);
            
            % Build the transformation matrix        
            R = vecmath.RotateAxis(mu_vis, mmag_vis);
            gR = vecmath.TransformMat(R, fvec_vis);            
            % Scale size of pyramid based on fx component   
            xs = vecmath.changeScale(fvec_vis(1), app.VisLim(1,:), app.py_scale_range);
            gx = vecmath.scaleMat(xs);            
            % Combine into Transform Matrix Tmat
            Tmat = gR*gx;
            
        end
    
        function updFBPyramid(app, wvec)
            
            if ~isempty(app.FigH)              
                
                % Compute transform matrix 
                [Tmat, wmag] = app.wrench2Tmat(wvec);
                
                % Update FBPyramid object in TargetWindow 
                app.FigH.updateFBPyramid(Tmat);
                app.FigH.updateGaugeValue(wmag)
                
                % Update gauge color based on if direction matches target               
                switch app.directionMatch(wvec)
                    case 0 % does not match
                        c = 'y';
                    case 1 % matches
                        c = 'g';
                end
                app.FigH.changeGaugeFillColor(c);               
                
            end
                        
        end
    
        function bool = directionMatch(app, wvec)
        % input:  wvec (6x1) wrench vector 
        % output: bool :    0 - does not match target direction
        %                   1 - matches target direction
        
        % CHECK THIS 
        % what does the angle between two 6dof vectors really mean? 
        % should I do the angle between F and M separately and take the norm?             
            
            % only consider active components
            wvec = app.FBCompsMat*wvec;
            tvec = app.FBCompsMat*app.TargetValues;
            
            errormode = 'angle';
            switch errormode
                case {0, 'angle'}
                    
                    % compute angle between the 2 vectors
                    th = vecmath.aBetween2Vecs(wvec,tvec);
                    
                    % Compare difference between wvec and tvec vectors to allowable error window 
                    if isnan(th) % magnitude of wvec or tvec == 0
                        bool = 0;
                    else
                        if abs(th) < deg2rad(app.error_window) % matches target direction
                            bool = 1;
                        else % does not match 
                            bool = 0;
                        end
                    end
                    
                case {1, 'norm'}
                    wu = vecmath.unitvec(wvec);
                    tu = vecmath.unitvec(tvec);
                    
                    nerr = norm(wu-tu);
                    
                    if nerr < app.error_window % matches target direction
                        bool = 1; % 
                    else % does not match
                        bool = 0;
                    end                    
                   
                    
                case {2, 'individual'}
                    wu = vecmath.unitvec(wvec);
                    tu = vecmath.unitvec(tvec);
                    
                    err = abs(wu-tu);
                    
                    if all(err < app.error_window) % matches target direction
                        bool = 1;
                    else % does not match 
                        bool = 0; 
                    end
                    
                otherwise
                    warning('invalid errormode case')
                    bool = 0;
            end
            
            
        end

        function updateTarget(app)
            
            % only execute if figure window is open and show target checkbox is checked
            if ~isempty(app.FigH)

                % make transform matrix                 
                Tmat = app.wrench2Tmat(app.TargetValues);
                
                switch app.TargetMode
                    case {1,'1'}
                        % TargetWindow function 
                        app.FigH.updateTargetPyramid(Tmat);  
                    case {2,'2'} % dir and mag
                        % TargetWindow function 
                        app.FigH.updateTargetPyramid(Tmat);
                    otherwise
                        warning('invalid app.TargetMode selection')
                end
               
            end
            
        end
    
        function setGaugeLims(app)
            if ~isempty(app.FigH)
                % change gauge limits
                %maxw = vecmath.maxnorm(app.WRanges);           
                app.FigH.changeGaugeLimits([0 1]);
                
            end
           
        end   
        
        
        % left hand coordinate frame :(
        % Compute Coordinate Transform for Load cell Frame -> World Frame -> Head Frame
        function gT = computeCoordinateTransform(app, p, th)
            %   p: translation vector (x,y,z) from world frame to head frame 
            %   th: vector of angles about each axis (thX, thY, thZ) to rotate in order z-y-x
            %   gT: adjoint matrix to transform wrench from load cell to head frame coordinates
            
            % Load Cell Coordinate Frame: located at center of load cell 
            %       x: away from label
            %       y: toward cable connection perp to x
            %       z: forward (out from sensor side)
            % (R-Hand coordinate frame from robot side, L-Hand from sensor side)
            
            % World Coordinate Frame: located at center of load cell 
            %       x:  forward (horizontal to ground)
            %       y:  vertical up (parallel to gravity) 
            %       z:  to subject's right (horizontal to ground)
            % 
            
            % Head Coordinate Frame: located at C2 dens
            %       x:  forward
            %       y:  vertical up
            %       z:  to subject's right          
            % 
            
            
            % Transform load cell to world frame

            % Reflection about xy plane  
            Rr = vecmath.ReflectAboutPlane('xy');
            % rotate 90 deg about y axis 
            Ry = vecmath.RotateAxis([0 1 0]',deg2rad(-90));
            %p = [0.1 0 0]; % move .1 m in x direction of world frame
            p0 = zeros(1,3);

            A_LC2W = vecmath.adjointMatrix(Ry*Rr,p0);
                       
            
            switch app.FBFrameMode
                case {1, '1', 'Hframe'} % head frame
                    % create rotation matrices about each axis and translation vector             
                    Rx = vecmath.RotateAxis([1 0 0]', th(1));
                    Ry = vecmath.RotateAxis([0 1 0]', th(2));
                    Rz = vecmath.RotateAxis([0 0 1]', th(3));                    
                    % adjoint matrix from world frame to head frame
                    A_W2H = vecmath.adjointMatrix(Rx*Ry*Rz, p);                    
                    % adjoint matrix from load cell to head frame
                    gT = A_LC2W * A_W2H;
                case {2, '2', 'Wframe'} % world frame
                    gT = A_LC2W;
                case {3, '3', 'LCframe'} % load cell frame
                    gT = eye(6);
                otherwise % not transformed
                    gT = eye(6);
            end                   
            
        end
    
        
        function new_value = deleteHandle(~,handle)
            % delete handle and set value to empty
            if ~isempty(handle)
                for ii = 1 : length(handle) % single or list of handles
                    if ishandle(handle(ii))
                        delete(handle(ii))
                    end                    
                end
                new_value = []; % set handle to empty
            else % if already empty
                new_value = handle; % pass through
            end
            
        end
    
        
        function new_value = closeFigure(~,handle)
            % close figure window and delete handle
            if ~isempty(handle)
                if ishandle(handle)
                    close(handle) % executes figure's CloseRequestFcn
                end
                new_value = [];
            else
                new_value = handle;
            end         
            
        end 
    
        function lh = clearLine(~, lh)
            % This clears the data in the line handle but does not delete the handle 
            for ii = 1 : length(lh)
                if ishandle(lh(ii))
                    set(lh(ii), 'XData', [],'YData', [])
                end
            end
        end        
    
        function EnableDisableTargetWindowOpen(app, bool)
            
           switch bool 
               
               case 0 % close window
                   
                   % Change button text 
                    app.OpenTargetWindowButton.Text = 'Open Target Window';                  
                    
                    % re-enable changing mode 
                    app.TargetModeDropDown.Enable = 'on';
               
               case 1 % open window
                   
                   % Change button text 
                    app.OpenTargetWindowButton.Text = 'Close Target Window';   
                    
                    % disable changing mode 
                    app.TargetModeDropDown.Enable = 'off';
           
           end
           
        end    

      
        function lh = initializePlots(~, ax, ch, ch_names)
            % ax = app.WrenchAxes or app.EMGAxes; % (1 x #ch) array of UIAxis handles
            % ch = app.WrenchChannels or app.EMGChannels; % (1 x #ch) array of daq channels
            % lh : (1 x #ch) array of line handles 
            
            nCh = length(ch);
            lh = [];                      
                
            % Create a line plot and store its handle
            for ii = 1 : nCh
                title(ax(ii),ch_names(ii))
                lh(ii) = plot(ax(ii), NaN, NaN);                
            end
           
        end    

    
        function data = storeDataInFIFO(~, data, buffersize, datablock)
            % storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
            %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
            %  a smooth live plot update and without continuously increasing memory use.
            %  The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the 
            %  buffer exceeds the specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of
            %  the data in the buffer to BUFFERSIZE.
            %  input DATA is the existing data buffer (array nchan x N).
            %  BUFFERSIZE is the desired buffer size (maximum number of columns in DATA buffer) and can be changed.
            %  DATABLOCK is a new data block to be added to the buffer (array nchan x K).
            %  output DATA is the updated data buffer (array nchan x M).
            
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
                
            % length of data and data block (number of samples)
            ndata = size(data,2);
            ndatablock = size(datablock,2);
            
            if ndata > buffersize
                data = data(:,end-buffersize+1:end);
            end
            
            if ndatablock < buffersize
                % Data block size (number of columns) is smaller than the buffer size
                if ndata == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = ndatablock;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,end-shiftPosition+1:end) = datablock;
                elseif (ndata < buffersize) && (ndata + ndatablock > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data datablock];
                    shiftPosition = ndata - buffersize;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,buffersize+1:end) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(:,end-buffersize+1:end);
            end            
        end

        function DataAvailableCallback(app, ~, event)
            % dataAvailable_Callback Executes on DAQ session DataAvailable event
            %  This callback function gets executed periodically as more data is acquired.
            %  event.TimeStamps (nx1) and event.Data (n x nch)         
            
            % organize data and store in FIFO buffers (nch x n)
            app.RawDataFIFOBuffer = app.storeDataInFIFO(app.RawDataFIFOBuffer, app.FIFOMaxSize, event.Data');
            
            timestamps = event.TimeStamps'; % make (1 x #scans)
            app.TimestampsFIFOBuffer = app.storeDataInFIFO(app.TimestampsFIFOBuffer, app.FIFOMaxSize, timestamps);
            
            % transform wrench data to head frame
            wrenchdata = app.LC2Head' * app.CalMatrix * (event.Data(:,app.WrenchChannels+1)' - app.LCZeroOffset); % (#ch x #scans)
            app.WrenchFIFOBuffer = app.storeDataInFIFO(app.WrenchFIFOBuffer, app.FIFOMaxSize, wrenchdata);
            
            if ~isempty(app.EMGChannels)
                emgdata = event.Data(:,app.EMGChannels+1)'; % (#ch x #scans)
                app.EMGFIFOBuffer = app.storeDataInFIFO(app.EMGFIFOBuffer, app.FIFOMaxSize, emgdata);
            else
                emgdata = [];
            end
            
            % log data
            if app.logdata
                
                if isempty(app.starttime)
                    % set start time to first time stamp 
                    app.starttime = timestamps(1); 
                    app.TotalDataSaved = 0;
                end               
                
                % save to temp file 
                fwrite(app.TempFile, squeeze([timestamps;wrenchdata;emgdata]), 'double');
                
                % update count of data (used to keep track of time for end condition)
                app.TotalDataSaved = app.TotalDataSaved + length(timestamps);
                
                % check ending condition
                if app.TotalDataSaved >= app.sTime * app.sRate
                    % stop logging data
                    app.logdata = 0;
                    
                    % close temp file and save to .mat file 
                    app.saveDataFile(app.starttime, timestamps(end), app.TotalDataSaved);
                    
                    % enable buttons now that finished acquiring
                    app.EnableDisableAcquisition(1)
                    
                    % reset 
                    app.starttime = [];
                    app.TotalDataSaved = 0;  
                    
                    % update trial number for next trial 
                    app.itrial = app.itrial + 1;
                    app.TrialNumberField.Value = app.itrial;
                end             
                
            end
            
            % Update plot data if in current view
            if app.TabGroup.SelectedTab == app.WrenchPlotsTab
                app.updatePlots(app.WrenchAxes, app.WrenchLH, app.TimestampsFIFOBuffer, app.WrenchFIFOBuffer);
            end
            if all([app.TabGroup.SelectedTab == app.EMGPlotsTab, app.collectEMG, ~isempty(app.EMGChannels)])
                app.updatePlots(app.EMGAxes, app.EMGLH, app.TimestampsFIFOBuffer, app.EMGFIFOBuffer);
            end 
            
            
            % update target window if open too 
            if ~isempty(app.FigH)
                % transform wrench data for visual feedback in target display
                meandata = mean(wrenchdata,2);
                % update FBPyramid with new data
                app.updFBPyramid(meandata);
                
                if app.TabGroup.SelectedTab == app.GaugesTab
                    app.updateGauges(meandata);
                end            
                
            end          
        end        

    
        function [t,d] = binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % Outputs:
        %   t = a column vector ,  the first column in the data from binary file
        %   d = a 2-D array of doubles, includes 2nd-last columns in the data from binary file

            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,numColumns-1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), :) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
            
            % assign outputs 
            t = matObj.timestamps;
            d = matObj.data;
        end
    
    
        function saveDataFile(app, time0, timef, nScans)            
            
            % Close temporary binary file
            fclose(app.TempFile);           
            
            % Gather metadata in preparation for saving to MAT file
            % Store relevant Daq device info
            metadata = app.setupStruct();
            metadata.trialnum = app.itrial;  
            metadata.time0 = time0;
            metadata.timef = timef;
            metadata.nScans = nScans;           
            
            % add trial number to filename
            [~, fn, ~] = fileparts(app.filename);            
            new_fn = [fn '_' num2str(app.itrial) '.mat'];
            matFilepath = fullfile(app.datadir, new_fn);
            % if file exists, add _N to end until unique
            matFilepath = app.uniqueFileName(matFilepath, '.mat');
            
            app.StatusLabel.Text = 'Saving data to MAT file is in progress...';
            drawnow
            
            numColumns = length(squeeze([app.WrenchChannels app.EMGChannels])) + 1; %nchannels + 1 for timestamps
            
            % Convert data from binary file to MAT file
            [t,d] = app.binFile2MAT(app.TempFilename, matFilepath, numColumns, metadata);
            
            % take just the filename for status update
            [~, fn, ext] = fileparts(matFilepath); 
            app.StatusLabel.Text = sprintf('Saving data to ''%s'' file has completed.', [fn ext]);
            
            % plot data from logged file to new figure window
            wch = app.WrenchChannels + 1;
            [~, wax] = app.createNewPlotWindow(length(wch), 'Wrench Data', app.WrenchChannelNames);
            % d is npts x nch
            app.plotData2Ax(wax, t, d(:, wch))
            
            if ~isempty(app.EMGChannels)
                ech = app.EMGChannels + 1;
                [~, eax] = app.createNewPlotWindow(length(ech), 'EMG Data', app.EMGChannelNames);
                app.plotData2Ax(eax, t, d(:,ech))
                fdata = app.MovAvgFilter(d(:,ech), 250);
                app.plotData2Ax(eax, t, fdata)
            end
            
        end    
        
    
        function [f, ax] = createNewPlotWindow(~, nch, fname, chnames)
            
            f = figure('Name', fname);            
            
            % dimensions
            ncols = 2;
            nrows = ceil(nch/ncols);            
            
            % create subplots and save axes
            for ii = 1 : nch                
                ax(ii) = subplot(nrows,ncols,ii);
                hold on                
                
                if nargin > 3
                    title(ax(ii), chnames(ii))
                else
                    title(ax(ii), num2str(ii))
                end
            end
            
        end
    
        function plotData2Ax(~, ax, timestamps, data)
            % timestamps is a column vector
            % data (npts x nch)
            
            % plot data to ax 
            for ii = 1 : length(ax)              
                plot(ax(ii), timestamps, data(:,ii));                
            end
            
        end
    
        function fdata = MovAvgFilter(~, data, fwin)
            % Zero-Phase-Shift (2-sided) Moving Average filter: 
            %       Implemented with matlab filter(b,a,x) function where b = [1/fwin 1/fwin ...] of length(b) = fwin
            %       Based off of filter22() from Eric Perrault 1991                        
            % 
            % Inputs:   data (npts x nchannels) 
            %           fwin (scalar) filter window length
            % 
            % Outputs:  fdata (npts x nchannels) filtered data
        
            % dimensions 
            [npts, nch] = size(data);
        
            % filter numerator coefficients
            fil = ones(fwin,1)/fwin;
            % half length of filter coeff will be thrown out to avoid phase shift
            hl = ceil(length(fil)/2);
        
            % add padding to data before filtering for later phase shift correction
            padding = zeros(length(1:hl),nch);
            data = [data ; padding];
            
            % filter along first dimension
            fdata = filter(fil, 1, data, [], 1);
        
            % throw out first bit to avoid phase shift
            fdata = fdata(hl : npts + hl-1, :);
            
        end      
            

        function updatePlots(~, ax, lh, xdata, ydata)
            % ax = app.WrenchAxes or app.EMGAxes; %(1 x #Axes) array of UIAxis handles
            % xdata = app.TimestampsFIFOBuffer; % (1 x #scans) array
            % ydata = app.WrenchFIFOBuffer or app.EMGFIFOBuffer; % (#ch x #scans) array
            
            % set axis data objects
            if numel(xdata) > 1
                for ii = 1 : length(lh)
                    if ishandle(lh(ii))
                        set(lh(ii), 'XData', xdata, 'YData', ydata(ii,:));
                    end
                    if ishandle(ax(ii))
                        xlim(ax(ii), [xdata(1), xdata(end)]); 
                    end                        
                end
            end
            
        end    
       
        function cal_mat = loadCalMatrix(~,mat)

            if ismatrix(mat) 
                cal_mat = mat;               
            else 
                % If error, just use identity matrix is being used 
                cal_mat = eye(6);
                warndlg('Could not find calibration matrix. Using identity matrix instead.','Calibration Matrix');
            end

            
        end
        
        function closeApp_Callback(app, ~, event, isAcquiring)
            % closeApp_Callback Executes after "Close Confirm" dialog window
            %  "Close Confirm" dialog window is launched from DataAcquisitionLiveCloseRequest.
            %   before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   DAQ session and close file if logging.
            
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession);
                        app.deleteHandle(app.DataAvailableLH);
                        app.deleteHandle(app.DAQSession);
                    else
                        % Acquisition is stopped
                    end
                    
                    % Re-enable warning when session rate is adjusted to closest supported sampling rate
                    warning('on', 'daq:Session:closestRateChosen')
                    delete(app);
                case 'Cancel'
                    % Continue
            end
            
        end    
        
    
        function EnableDisableInitialization(app, bool)
           
            switch bool 
               
                case 0 % DAQ initialized and Background Collection Running                  
                    app.InitializeDAQButton.Enable = 'off';
                    app.scanRateField.Enable = 'off';
                    app.CollectEMGCheckBox.Enable = 'off';
                    app.nEMGSpinner.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.DAQDeviceDropDown.Enable = 'off';
                    app.AcquireDataButton.Enable = 'on';
                    app.LoadConfigButton.Enable = 'off';
                    
               
               case 1 % DAQ stopped and Cleared; not running 
                    app.InitializeDAQButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.scanRateField.Enable = 'on';
                    app.CollectEMGCheckBox.Enable = 'on';
                    app.nEMGSpinner.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.AcquireDataButton.Enable = 'off';
                    app.DAQDeviceDropDown.Enable = 'on';
                    app.LoadConfigButton.Enable = 'on';
                    
           end
        end
    
        function EnableDisableAcquisition(app, bool)
            
            switch bool
                
                case 0 % currently acquiring 
                    app.StatusLabel.Text = 'Status: Acquiring Data';
                    app.AcquireDataButton.Enable = 'off';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.sampleTimeField.Enable = 'off';
                    
                case 1 % not acquiring data
                    app.AcquireDataButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.sampleTimeField.Enable = 'on';
            end
        end      


        function setup = setupStruct(app)
            % app property values to save into setup structure 

            setup.subject = struct(  'Date',                 date,                    ...
                                     'subjID',               app.subjID,              ...
                                     'Protocol',             app.Protocol,            ...
                                     'Notes',                app.Notes);
            setup.daq = struct(      'WrenchChannels',       app.WrenchChannels,      ...
                                     'EMGChannels',          app.EMGChannels,         ...
                                     'WrenchChannelNames',  {app.WrenchChannelNames}, ...
                                     'EMGChannelNames',     {app.EMGChannelNames},    ...
                                     'sRate',                app.sRate,               ...
                                     'sTime',                app.sTime);
            setup.target = struct(   'TargetValues',         app.TargetValues,        ...
                                     'VisLim',               app.VisLim,              ...
                                     'WRanges',              app.WRanges);   
            setup.params = struct(   'LC2Head',              app.LC2Head,             ...
                                     'CalMatrix',            app.CalMatrix);            
            
        end
    
        function value = ifisfield(~, s, fstr, cval)
            % only assign value from struct if fstr is a field of the struct s
            % otherwise keep current value cval
            if isfield(s,fstr)
                value = s.(fstr);
            else
                value = cval;
            end
        end
    
        function loadConfigUpdate(app, setup)
            
            % LOAD VALUES FROM SETUP STRUCT
            % setup.subject
            s = setup.subject;
            app.subjID = app.ifisfield(s,'subjID',app.subjID);
            app.Protocol = app.ifisfield(s,'Protocol',app.Protocol);
            app.Notes = app.ifisfield(s,'Notes',app.Notes);
            % setup.daq
            s = setup.daq;
            app.WrenchChannels = app.ifisfield(s,'WrenchChannels',app.WrenchChannels);
            app.EMGChannels = app.ifisfield(s,'EMGChannels',app.EMGChannels);
            app.WrenchChannelNames = app.ifisfield(s,'WrenchChannelNames',app.WrenchChannelNames);
            app.EMGChannelNames = app.ifisfield(s,'EMGChannelNames',app.EMGChannelNames);
            app.sRate = app.ifisfield(s,'sRate',app.sRate);
            app.sTime = app.ifisfield(s,'sTime',app.sTime);
            % add nEMG
            % setup.target
            s = setup.target;
            app.TargetValues = app.ifisfield(s,'TargetValues',app.TargetValues);
            app.VisLim = app.ifisfield(s,'VisLim',app.VisLim);
            app.WRanges = app.ifisfield(s,'WRanges',app.WRanges);
            % setup.params
            s = setup.params;
            app.LC2Head = app.ifisfield(s,'LC2Head',app.LC2Head);           
            app.CalMatrix = app.ifisfield(s,'CalMatrix',app.CalMatrix);
            
            % UPDATE UI COMPONENTS            
            % subject
            app.ParticipantIDField.Value = app.subjID;
            app.ProtocolField.Value = app.Protocol;
            app.NotesField.Value = app.Notes;
            % daq
            app.scanRateField.Value = app.sRate;
            app.sampleTimeField.Value = app.sTime;
            % add nEMGspinner
            % target
            app.TargetTable.Data = app.TargetValues;
            app.TargetAxesLimitsTable.Data = [app.VisLim(1:3,:); rad2deg(app.VisLim(4:6,:))];           
            
        end
        
        function fullName = uniqueFileName(~, fullName, ext)
            % fullName includes the path (relative or not)
            % ext is the extension (ie '.mat')
            [pn, fn, ~] = fileparts(fullName);
            
            iter = 0;
            while isfile(fullName) % file exists
                iter = iter + 1;
                new_fn = [fn '_' num2str(iter) ext];
                fullName = fullfile(pn, new_fn);
            end
            
        end
    
        function initializeAppVars(app)
            
            % get calibration matrix 
            app.CalMatrix = app.loadCalMatrix(app.CalMatPath);
            
            % set to default values from ui control
            app.collectEMG = app.CollectEMGCheckBox.Value;
            
            app.sRate = app.scanRateField.Value;
            app.sTime = app.sampleTimeField.Value;
            
            app.subjID = app.ParticipantIDField.Value;
            app.Protocol = app.ProtocolField.Value;
            app.Notes = app.NotesField.Value;      
            
            app.TargetMode = str2num(app.TargetModeDropDown.Value);
            app.FBFrameMode = str2num(app.WrenchFrameDropDown.Value);
                        
            % uiAxes Handles
            app.WrenchAxes = [app.WrenchAxes1,app.WrenchAxes2,app.WrenchAxes3,...
                app.WrenchAxes4,app.WrenchAxes5,app.WrenchAxes6];
            app.EMGAxes = [app.EMGAxes1,app.EMGAxes2,app.EMGAxes3,...
                app.EMGAxes4,app.EMGAxes5,app.EMGAxes6];            
                
            % Target Axes 
            app.maxFSpinner.Value = max(max(app.WRanges(1:3,:)));
            app.maxMSpinner.Value = max(max(app.WRanges(4:6,:)));
            app.updateGaugeLims();
            
            % Target Values
            app.TargetTable.Data = app.TargetValues;
            app.changeGaugeColorLims();
            
            % Direction Match Error allowable 
            app.DirMatchErrorEditField.Value = rad2deg(app.error_window);
            app.ErrorModeDropDownValueChanged();
            
            % update adjoint matrix to change coordinates from load cell -> head frame
            W2LCdefault = [5 -5 0 0 0 0]';
            app.HeadFrameTable.Data = W2LCdefault;
            app.LC2Head = app.computeCoordinateTransform(W2LCdefault(1:3),W2LCdefault(4:6));         
            
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this is run at start up 
            
            % Detect all connected devices
            devices = daq.getDevices;
            
            % Store DAQ device information into DevicesInfo app property
            app.DevicesInfo = devices;
            
            % populate the device drop down list with cell array of composite device names (ID + model) 
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            app.DAQDeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DAQDeviceDropDown.ItemsData = 1:numel(devices)+1;  
            
            % initialize app. properties 
            app.initializeAppVars();                       
            
            % Current data directory
            app.CurrentDirectoryTextArea.Value = app.datadir;


        end

        % Value changed function: OpenTargetWindowButton
        function OpenTargetWindowButtonValueChanged(app, event)
            targetOpen = app.OpenTargetWindowButton.Value;
            
            switch targetOpen
                
                case 0 % Close Figure and Reset Objects                    
                    
                    % update gui
                    app.EnableDisableTargetWindowOpen(0);
                    
                    % Close Figure and delete handle 
                    delete(app.FigH);
                    app.FigH = [];  
                    
                    
                case 1 % Open Figure and Initialize Objects
                    % update gui
                    app.EnableDisableTargetWindowOpen(1);
                    
                    % TargetWindow Mode                 
                    switch app.TargetModeDropDown.Value
                        
                        case {1,'1'} % match
                            % open figure and axes for wrench feedback 
                            app.FigH = TargetWindow();
                            app.FigH.createFBPyramid();
                            app.FigH.createTargetPyramid();                            
                            
                        case {2,'2'} % dir and mag
                            app.FigH = TargetWindow();
                            app.FigH.createFBPyramid();
                            app.FigH.createTargetPyramid();

                        otherwise
                            error('invalid TargetModeDropDown.Value')
                    end
                    
                    % set up VisLim property for proper scaling 
                    app.axlim2vislim();
                    
                    % set view
                    app.ViewDropDownValueChanged();
                    app.FigH.changeFBPyramidTransparency(app.FBTransparencySpinner.Value);
                    app.FigH.changeTPyramidTransparency(app.TargetTransparencySpinner.Value);                    
                    
                    % set gauge limits
                    app.setGaugeLims();
                    
                    % initialize
                    app.ShowTargetCheckBoxValueChanged();
                    app.ShowFeedbackCheckBoxValueChanged();               
                    
            end
            
        end

        % Cell edit callback: TargetTable
        function TargetSetValueChanged(app, event)
            
            % update target settings property                        
            app.TargetValues = app.TargetTable.Data;             
                       
            % update target
            app.updateTarget();
            app.changeGaugeColorLims() 
            
        end

        % Button pushed function: ZeroLoadCellButton
        function ZeroLoadCellButtonPushed(app, event)
            if ~isempty(app.DAQSession) % daq is initialized
                if app.DAQSession.IsRunning  % daq is acquiring data
                    if ~isempty(app.RawDataFIFOBuffer) % has acquired at least a some data
                        % set app property to current load cell data 
                        cdata = app.RawDataFIFOBuffer(app.WrenchChannels+1,:);
                        amt = size(cdata,2);
                        if amt > 50
                            LCData = mean(cdata(:,end-50:end),2);
                        else
                            LCData = mean(cdata,2);
                        end                        
                        app.LCZeroOffset = LCData;
                        
                        debug = 0;
                        if debug == 1
                            disp('LCOffset = ')
                            disp(LCData)
                        end
                        
                    end
                end

            end           

        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            
            deviceIndex = app.DAQDeviceDropDown.Value - 1;
            
            if deviceIndex > 0
                
                % disable/enable Buttons 
                app.EnableDisableInitialization(0);
                
                % Delete DAQ objects if they already exist to avoid errors            
                app.DAQSession = app.deleteHandle(app.DAQSession);    
                app.DataAvailableLH = app.deleteHandle(app.DataAvailableLH);
                
                % Get the device information needed
                deviceID = app.DevicesInfo(deviceIndex).ID;
                vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                
                % Create a new DAQ session
                app.DAQSession = daq.createSession(vendor);
                measurementType = 'Voltage';
                addAnalogInputChannel(app.DAQSession, deviceID, [app.WrenchChannels app.EMGChannels], measurementType);
                
                % Set DAQ object sampling rate and time
                app.DAQSession.Rate = app.sRate;
                app.DAQSession.IsContinuous = true;
                app.DAQSession.NotifyWhenDataAvailableExceeds = 0.1*app.sRate; %CHECK THIS
                
                % Create listener for DataAvailable event and specify callback function
                app.DataAvailableLH = addlistener(app.DAQSession,'DataAvailable', ...
                    @(src,event) DataAvailableCallback(app, src, event));                              
                
                % reset FIFO buffer data
                app.WrenchFIFOBuffer = [];
                app.EMGFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
                
                % start collecting data in background (continuous mode)
                startBackground(app.DAQSession); 
                
                % initialize plots
                app.WrenchLH = app.initializePlots(app.WrenchAxes, app.WrenchChannels+1, app.WrenchChannelNames);
                
                if app.collectEMG == 1 && ~isempty(app.EMGChannels)
                    app.EMGLH = app.initializePlots(app.EMGAxes, app.EMGChannels+1, app.EMGChannelNames);
                end
                
            else
                % If no device is selected
                uialert(app.TargetDAQUI,'Select a valid DAQ Device from the dropdown menu.','No DAQ Device Selected')
            end   
                 
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % disable/enable Buttons
            app.EnableDisableInitialization(1);
            
            % stop and clear DAQ session
            if ~isempty(app.DAQSession)
                stop(app.DAQSession);
                app.DAQSession = app.deleteHandle(app.DAQSession);
            end            
            
            % clear line handles to remove feedback 
            if ishandle(app.FigH) % target window is open
               % fix
            end
            
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            if ~isempty(app.subjID)
                
                setup = app.setupStruct();
                
                if ~isempty(app.datadir)
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.datadir '\' app.subjID '_Setup.mat']);
                else 
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.subjID '_Setup.mat']);
                end
                
                if fname ~= 0
                    save(fullfile(pname,fname),'setup');
                else
                    warndlg('Setup file was not saved','Save Setup')
                end
                
            else
                warndlg('Please fill the participant information form first','Save Setup');
            end           
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            
            if ~isempty(app.datadir)
                [fname,pname] = uigetfile([app.datadir '\*.mat'], 'Select the setup file (*.mat)');
            else 
                [fname,pname] = uigetfile([pwd '\*.mat'], 'Select the setup file (*.mat)');
            end
            
            if fname ~= 0 
                load(fullfile(pname,fname), 'setup');
                if exist('setup')
                    app.loadConfigUpdate(setup);                           
                end
            end
        end

        % Value changed function: ParticipantIDField
        function ParticipantIDFieldValueChanged(app, event)
            app.subjID = app.ParticipantIDField.Value;            
        end

        % Value changed function: ProtocolField
        function ProtocolFieldValueChanged(app, event)
            app.Protocol = app.ProtocolField.Value;            
        end

        % Value changed function: NotesField
        function NotesFieldValueChanged(app, event)
            app.Notes = app.NotesField.Value;            
        end

        % Value changed function: scanRateField
        function scanRateFieldValueChanged(app, event)
            app.sRate = app.scanRateField.Value;            
        end

        % Value changed function: sampleTimeField
        function sampleTimeFieldValueChanged(app, event)
            app.sTime = app.sampleTimeField.Value;            
        end

        % Value changed function: CollectEMGCheckBox
        function CollectEMGCheckBoxValueChanged(app, event)
            collectEMGbool = app.CollectEMGCheckBox.Value;
            
            % save state into property
            app.collectEMG = collectEMGbool;
            
            switch collectEMGbool
                
                case 0 % unchecked
                    
                    % stop collecting EMG
                    app.nEMGSpinner.Value = 0;
                    app.nEMGSpinner.Enable = 'off';
                    app.EMGChannels = [];
                    
                case 1 % checked
                    
                    % start collecting EMG signals                    
                    app.nEMGSpinner.Enable = 'on';
                    
            end
            
            % run value change callback
            app.nEMGSpinnerValueChanged()                
            
        end

        % Value changed function: nEMGSpinner
        function nEMGSpinnerValueChanged(app, event)
            
            % number of EMG channels to collect
            nEMG = app.nEMGSpinner.Value;           
            
            % set EMGChannels property to correct channels and initialize plots 
            if (nEMG > 0) && (app.collectEMG == 1)
                app.EMGChannels = app.EMGChannelsAvail(1:nEMG);                  
            else % nEMG == 0
                app.EMGChannels = [];                
            end 
            
            % delete previous line handles 
            app.EMGLH = app.deleteHandle(app.EMGLH);
            
            % update EMG axes and line handles
            app.EMGLH = app.initializePlots(app.EMGAxes, app.EMGChannels+1, app.EMGChannelNames);
            
        end

        % Close request function: TargetDAQUI
        function TargetDAQUICloseRequest(app, event)

            if ~isempty(app.DAQSession)
                if app.DAQSession.IsRunning
                    acquiring = 1;
                else
                    acquiring = 0;
                end
            else
                acquiring = 0;
            end
            
            if acquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.TargetDAQUI, question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,acquiring));                                  
            
        end

        % Value changed function: DAQDeviceDropDown
        function DAQDeviceDropDownValueChanged(app, event)
            
            app.daqDevice = app.DAQDeviceDropDown.Value - 1;                           

        end

        % Value changed function: ShowTargetCheckBox
        function ShowTargetCheckBoxValueChanged(app, event)
            
            % get current value 
            bool = app.ShowTargetCheckBox.Value;
            
            if ~isempty(app.FigH) % target feedback window open
                switch bool                   
                    case 0 % unchecked: don't show target
                        app.FigH.targetPyramidVisible('off')
                    case 1 % checked: show target
                        app.FigH.targetPyramidVisible('on')
                        app.updateTarget();
                end                
            end

           
        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
           
            % disable buttons and update status for acquisition
            app.EnableDisableAcquisition(0)
            
            % unique temporary file name and open handle to file for saving 
            app.TempFilename = tempname;
            app.TempFile = fopen(app.TempFilename, 'w');                   
            
            % set logdata flag to 1 to start saving data to file in data available callback function 
            app.logdata = 1;
            
        end

        % Button pushed function: CurrentDirectoryButton
        function CurrentDirectoryButtonPushed(app, event)
            app.datadir = uigetdir(cd, 'Select Directory to Save to');
            app.CurrentDirectoryTextArea.Value = app.datadir;
        end

        % Value changed function: TrialNumberField
        function TrialNumberFieldValueChanged(app, event)
            app.itrial = app.TrialNumberField.Value;            
        end

        % Cell edit callback: HeadFrameTable
        function HeadFrameTableCellEdit(app, event)
        
            % get location and orientation of head frame from ui table
            d = app.HeadFrameTable.Data;
            th = deg2rad(d(4:6)); % (deg -> rad)
            p = d(1:3); % (m)
            
            % adjoint matrix to transform between coordinate frames
            app.LC2Head = app.computeCoordinateTransform(p,th);            
            
        end

        % Value changed function: TargetModeDropDown
        function TargetModeDropDownValueChanged(app, event)
            app.TargetMode = str2num(app.TargetModeDropDown.Value);
            
        end

        % Value changed function: WrenchFrameDropDown
        function WrenchFrameDropDownValueChanged(app, event)
            app.FBFrameMode = str2num(app.WrenchFrameDropDown.Value);            
            
            % update LC2Head transform 
            app.HeadFrameTableCellEdit()
        end

        % Value changed function: ShowFeedbackCheckBox
        function ShowFeedbackCheckBoxValueChanged(app, event)
            bool = app.ShowFeedbackCheckBox.Value;
            
            if ~isempty(app.FigH)
                
                switch bool
                    
                    case 0 % unchecked
                        visprop = 'off';
                    case 1 % checked
                        visprop = 'on';
                end
                
                app.FigH.FBPyramidVisible(visprop);
  
            end                  
            
        end

        % Value changed function: DirMatchErrorEditField
        function DirMatchErrorEditFieldValueChanged(app, event)
            
            app.error_window = app.DirMatchErrorEditField.Value;
            
        end

        % Value changed function: FxCheckBox, FyCheckBox, FzCheckBox, 
        % MxCheckBox, MyCheckBox, MzCheckBox
        function ActiveCompCheckBoxValueChanged(app, event)
            app.FBCompsMat = diag([app.FxCheckBox.Value, app.FyCheckBox.Value, app.FzCheckBox.Value, ...
                                   app.MxCheckBox.Value, app.MyCheckBox.Value, app.MzCheckBox.Value]); 
            app.updateTarget(); 
            
            app.setGaugeLims();
            app.updateGaugeLims();
        end

        % Value changed function: maxFSpinner, maxMSpinner
        function maxFMValueChanged(app, event)
            
            % get values
            maxF = app.maxFSpinner.Value;
            maxM = app.maxMSpinner.Value;
            
            % copy to app property
            app.WRanges = [-maxF maxF; -maxF maxF; -maxF maxF; -maxM maxM; -maxM maxM; -maxM maxM];
            
            % update table            
            app.setGaugeLims();
            app.updateGaugeLims();
            
            
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            viewmode = app.ViewDropDown.Value;
            
            if ~isempty(app.FigH)
                app.FigH.changeCameraPosition(viewmode)
            end
            
            switch viewmode
                case {"back"}
                    app.py_scale_range = sort(app.py_scale_range,'descend');
                case {"mirror"}
                    app.py_scale_range = sort(app.py_scale_range,'ascend');
                otherwise
                    warning('invalid viewmode')
            end
            
            % need to update target because Fx scaling changed
            app.TargetSetValueChanged();
            
            
        end

        % Value changed function: TargetTransparencySpinner
        function TargetTransparencySpinnerValueChanged(app, event)
            
            if ~isempty(app.FigH)
                app.FigH.changeTPyramidTransparency(app.TargetTransparencySpinner.Value);
            end
            
        end

        % Value changed function: FBTransparencySpinner
        function FBTransparencySpinnerValueChanged(app, event)

            if ~isempty(app.FigH)
                app.FigH.changeFBPyramidTransparency(app.FBTransparencySpinner.Value);
            end
            
        end

        % Value changed function: ErrorModeDropDown
        function ErrorModeDropDownValueChanged(app, event)
            app.ErrorMode = str2num(app.ErrorModeDropDown.Value);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetDAQUI = uifigure;
            app.TargetDAQUI.Name = 'UI Figure';
            app.TargetDAQUI.CloseRequestFcn = createCallbackFcn(app, @TargetDAQUICloseRequest, true);
            app.TabGroup = uitabgroup(app.TargetDAQUI);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DAQConfigurationPanel = uipanel(app.SetupTab);
            app.DAQConfigurationPanel.Title = 'DAQ Configuration';
            app.DAQDeviceDropDownLabel = uilabel(app.DAQConfigurationPanel);
            app.DAQDeviceDropDownLabel.Text = 'DAQ Device';
            app.DAQDeviceDropDown = uidropdown(app.DAQConfigurationPanel);
            app.DAQDeviceDropDown.Items = {'Select a Device'};
            app.DAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQDeviceDropDownValueChanged, true);
            app.DAQDeviceDropDown.Value = 'Select a Device';
            app.ScanRateLabel = uilabel(app.DAQConfigurationPanel);
            app.ScanRateLabel.Text = 'Scan Rate';
            app.scanRateField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.scanRateField.Limits = [0 Inf];
            app.scanRateField.ValueChangedFcn = createCallbackFcn(app, @scanRateFieldValueChanged, true);
            app.scanRateField.Value = 1000;
            app.SampleTimeLabel = uilabel(app.DAQConfigurationPanel);
            app.SampleTimeLabel.Text = 'Sample Time';
            app.sampleTimeField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.sampleTimeField.Limits = [0 Inf];
            app.sampleTimeField.ValueChangedFcn = createCallbackFcn(app, @sampleTimeFieldValueChanged, true);
            app.sampleTimeField.Value = 5;
            app.InitializeDAQButton = uibutton(app.DAQConfigurationPanel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.SubjectInformationPanel = uipanel(app.SetupTab);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.ParticipantIDEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ParticipantIDField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDFieldValueChanged, true);
            app.ProtocolEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ProtocolEditFieldLabel.Text = 'Protocol';
            app.ProtocolField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ProtocolField.ValueChangedFcn = createCallbackFcn(app, @ProtocolFieldValueChanged, true);
            app.NotesTextAreaLabel = uilabel(app.SubjectInformationPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesField = uitextarea(app.SubjectInformationPanel);
            app.NotesField.ValueChangedFcn = createCallbackFcn(app, @NotesFieldValueChanged, true);
            app.EMGOptionsPanel = uipanel(app.SetupTab);
            app.EMGOptionsPanel.Title = 'EMG Options';
            app.CollectEMGCheckBox = uicheckbox(app.EMGOptionsPanel);
            app.CollectEMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollectEMGCheckBoxValueChanged, true);
            app.CollectEMGCheckBox.Text = 'Collect EMG';
            app.ofEMGsSpinnerLabel = uilabel(app.EMGOptionsPanel);
            app.ofEMGsSpinnerLabel.Enable = 'off';
            app.ofEMGsSpinnerLabel.Text = '# of EMGs';
            app.nEMGSpinner = uispinner(app.EMGOptionsPanel);
            app.nEMGSpinner.Limits = [0 6];
            app.nEMGSpinner.ValueChangedFcn = createCallbackFcn(app, @nEMGSpinnerValueChanged, true);
            app.nEMGSpinner.Enable = 'off';
            app.FileOptionsPanel = uipanel(app.SetupTab);
            app.FileOptionsPanel.Title = 'File Options';
            app.CurrentDirectoryButton = uibutton(app.FileOptionsPanel, 'push');
            app.CurrentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentDirectoryButtonPushed, true);
            app.CurrentDirectoryButton.Text = {'Current '; 'Directory'};
            app.CurrentDirectoryTextArea = uitextarea(app.FileOptionsPanel);
            app.basefilenameLabel = uilabel(app.FileOptionsPanel);
            app.basefilenameLabel.Text = 'base filename';
            app.basefilenameEditField = uieditfield(app.FileOptionsPanel, 'text');
            app.basefilenameEditField.Value = 'data_trial';
            app.SaveLoadPanel = uipanel(app.SetupTab);
            app.SaveLoadPanel.Title = 'Save Load';
            app.SaveConfigButton = uibutton(app.SaveLoadPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.SaveLoadPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.TransformTab = uitab(app.TabGroup);
            app.TransformTab.Title = 'Transform';
            app.BonyLandmarkTableLabel = uilabel(app.TransformTab);
            app.BonyLandmarkTableLabel.Text = 'Head Frame (in World Frame)';
            app.HeadFrameTable = uitable(app.TransformTab);
            app.HeadFrameTable.ColumnName = {''};
            app.HeadFrameTable.RowName = {'X (m)'; 'Y (m)'; 'Z (m)'; 'thX (deg)'; 'thY (deg)'; 'thZ (deg)'};
            app.HeadFrameTable.ColumnEditable = true;
            app.HeadFrameTable.CellEditCallback = createCallbackFcn(app, @HeadFrameTableCellEdit, true);
            app.XForwardYVerticalupZRightLabel = uilabel(app.TransformTab);
            app.XForwardYVerticalupZRightLabel.Text = {'X(+):  Forward'; 'Y(+):  Vertical (up)'; 'Z(+):  Right '};
            app.WrenchFrameDropDownLabel = uilabel(app.TransformTab);
            app.WrenchFrameDropDownLabel.Text = 'Wrench Frame';
            app.WrenchFrameDropDown = uidropdown(app.TransformTab);
            app.WrenchFrameDropDown.Items = {'Head', 'World', 'LoadCell'};
            app.WrenchFrameDropDown.ItemsData = {'1', '2', '3'};
            app.WrenchFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @WrenchFrameDropDownValueChanged, true);
            app.WrenchFrameDropDown.Value = '1';
            app.SetTargetTab = uitab(app.TabGroup);
            app.SetTargetTab.Title = 'Set Target';
            app.TargetSettingsPanel = uipanel(app.SetTargetTab);
            app.TargetSettingsPanel.Title = 'Target Settings ';
            app.TargetDirectionLabel = uilabel(app.TargetSettingsPanel);
            app.TargetDirectionLabel.Text = 'Target Direction';
            app.TargetTable = uitable(app.TargetSettingsPanel);
            app.TargetTable.ColumnName = {'Target'};
            app.TargetTable.RowName = {'Fx (N)'; 'Fy (N)'; 'Fz (N)'; 'Mx (Nm)'; 'My (Nm)'; 'Mz (Nm)'};
            app.TargetTable.ColumnEditable = true;
            app.TargetTable.CellEditCallback = createCallbackFcn(app, @TargetSetValueChanged, true);
            app.OpenTargetWindowButton = uibutton(app.SetTargetTab, 'state');
            app.OpenTargetWindowButton.ValueChangedFcn = createCallbackFcn(app, @OpenTargetWindowButtonValueChanged, true);
            app.OpenTargetWindowButton.Text = 'Open Target Window';
            app.ZeroLoadCellButton = uibutton(app.SetTargetTab, 'push');
            app.ZeroLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroLoadCellButtonPushed, true);
            app.ZeroLoadCellButton.Text = 'Zero Load Cell';
            app.WrenchRangesPanel = uipanel(app.SetTargetTab);
            app.WrenchRangesPanel.Title = 'Wrench Ranges';
            app.maxFSpinnerLabel = uilabel(app.WrenchRangesPanel);
            app.maxFSpinnerLabel.Text = 'max F';
            app.maxFSpinner = uispinner(app.WrenchRangesPanel);
            app.maxFSpinner.Limits = [0 Inf];
            app.maxFSpinner.ValueChangedFcn = createCallbackFcn(app, @maxFMValueChanged, true);
            app.maxFSpinner.Value = 30;
            app.maxMSpinnerLabel = uilabel(app.WrenchRangesPanel);
            app.maxMSpinnerLabel.Text = 'max M';
            app.maxMSpinner = uispinner(app.WrenchRangesPanel);
            app.maxMSpinner.Limits = [0 Inf];
            app.maxMSpinner.ValueChangedFcn = createCallbackFcn(app, @maxFMValueChanged, true);
            app.maxMSpinner.Value = 30;
            app.lbLabel = uilabel(app.WrenchRangesPanel);
            app.lbLabel.Text = 'lb';
            app.lbinLabel = uilabel(app.WrenchRangesPanel);
            app.lbinLabel.Text = 'lb in';
            app.TargetModeDropDownLabel = uilabel(app.SetTargetTab);
            app.TargetModeDropDownLabel.Text = 'Target Mode';
            app.TargetModeDropDown = uidropdown(app.SetTargetTab);
            app.TargetModeDropDown.Items = {'Match', 'Dir and Mag'};
            app.TargetModeDropDown.ItemsData = {'1', '2'};
            app.TargetModeDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetModeDropDownValueChanged, true);
            app.TargetModeDropDown.Value = '1';
            app.ActiveComponentsPanel = uipanel(app.SetTargetTab);
            app.ActiveComponentsPanel.Title = 'Active Components';
            app.FxCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.FxCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.FxCheckBox.Text = ' Fx';
            app.FxCheckBox.Value = true;
            app.FyCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.FyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.FyCheckBox.Text = ' Fy';
            app.FyCheckBox.Value = true;
            app.FzCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.FzCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.FzCheckBox.Text = ' Fz';
            app.FzCheckBox.Value = true;
            app.MxCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.MxCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.MxCheckBox.Text = ' Mx';
            app.MxCheckBox.Value = true;
            app.MyCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.MyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.MyCheckBox.Text = ' My';
            app.MyCheckBox.Value = true;
            app.MzCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.MzCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.MzCheckBox.Text = ' Mz';
            app.MzCheckBox.Value = true;
            app.TargetWindowOptionsPanel = uipanel(app.SetTargetTab);
            app.TargetWindowOptionsPanel.Title = 'Target Window Options';
            app.ShowTargetCheckBox = uicheckbox(app.TargetWindowOptionsPanel);
            app.ShowTargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTargetCheckBoxValueChanged, true);
            app.ShowTargetCheckBox.Text = 'Show Target';
            app.ShowTargetCheckBox.Value = true;
            app.ShowFeedbackCheckBox = uicheckbox(app.TargetWindowOptionsPanel);
            app.ShowFeedbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFeedbackCheckBoxValueChanged, true);
            app.ShowFeedbackCheckBox.Text = 'Show Feedback';
            app.ShowFeedbackCheckBox.Value = true;
            app.ViewDropDownLabel = uilabel(app.TargetWindowOptionsPanel);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.TargetWindowOptionsPanel);
            app.ViewDropDown.Items = {'back', 'mirror'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'back';
            app.TargetTransparencySpinnerLabel = uilabel(app.TargetWindowOptionsPanel);
            app.TargetTransparencySpinnerLabel.Text = 'Target Transparency';
            app.TargetTransparencySpinner = uispinner(app.TargetWindowOptionsPanel);
            app.TargetTransparencySpinner.Step = 0.05;
            app.TargetTransparencySpinner.Limits = [0.05 1];
            app.TargetTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @TargetTransparencySpinnerValueChanged, true);
            app.TargetTransparencySpinner.Value = 0.7;
            app.FBTransparencySpinnerLabel = uilabel(app.TargetWindowOptionsPanel);
            app.FBTransparencySpinnerLabel.Text = 'FB Transparency';
            app.FBTransparencySpinner = uispinner(app.TargetWindowOptionsPanel);
            app.FBTransparencySpinner.Step = 0.05;
            app.FBTransparencySpinner.Limits = [0.05 1];
            app.FBTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @FBTransparencySpinnerValueChanged, true);
            app.FBTransparencySpinner.Value = 0.7;
            app.DirectionMatchingErrorPanel = uipanel(app.SetTargetTab);
            app.DirectionMatchingErrorPanel.Title = 'Direction Matching Error';
            app.MatchErrorLabel = uilabel(app.DirectionMatchingErrorPanel);
            app.MatchErrorLabel.Text = 'Match Error';
            app.DirMatchErrorEditField = uieditfield(app.DirectionMatchingErrorPanel, 'numeric');
            app.DirMatchErrorEditField.Limits = [0 360];
            app.DirMatchErrorEditField.ValueChangedFcn = createCallbackFcn(app, @DirMatchErrorEditFieldValueChanged, true);
            app.DirMatchErrorEditField.Value = 10;
            app.ErrorModeDropDownLabel = uilabel(app.DirectionMatchingErrorPanel);
            app.ErrorModeDropDownLabel.Text = 'Error Mode';
            app.ErrorModeDropDown = uidropdown(app.DirectionMatchingErrorPanel);
            app.ErrorModeDropDown.Items = {'Angle', 'Norm', 'Individual'};
            app.ErrorModeDropDown.ItemsData = {'1', '2', '3'};
            app.ErrorModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ErrorModeDropDownValueChanged, true);
            app.ErrorModeDropDown.Value = '1';
            app.WrenchPlotsTab = uitab(app.TabGroup);
            app.WrenchPlotsTab.Title = 'WrenchPlots';
            app.WrenchAxes1 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes2 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes3 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes4 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes5 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes6 = uiaxes(app.WrenchPlotsTab);
            app.EMGPlotsTab = uitab(app.TabGroup);
            app.EMGPlotsTab.Title = 'EMGPlots';
            app.EMGAxes1 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes2 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes3 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes4 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes5 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes6 = uiaxes(app.EMGPlotsTab);
            app.GaugesTab = uitab(app.TabGroup);
            app.GaugesTab.Title = 'Gauges';
            app.WMagGaugeLabel = uilabel(app.GaugesTab);
            app.WMagGaugeLabel.Text = 'W Mag';
            app.WMagGauge = uigauge(app.GaugesTab, 'linear');
            app.WMagEditField = uieditfield(app.GaugesTab, 'numeric');
            app.ForcesPanel = uipanel(app.GaugesTab);
            app.ForcesPanel.Title = 'Forces';
            app.FMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FxMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FyMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FzMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FMagGauge_2Label = uilabel(app.ForcesPanel);
            app.FMagGauge_2Label.Text = 'F Mag';
            app.FMagGauge = uigauge(app.ForcesPanel, 'linear');
            app.FxGaugeLabel = uilabel(app.ForcesPanel);
            app.FxGaugeLabel.Text = 'Fx';
            app.FxGauge = uigauge(app.ForcesPanel, 'linear');
            app.FxGauge.Limits = [-1 1];
            app.FzGaugeLabel = uilabel(app.ForcesPanel);
            app.FzGaugeLabel.Text = 'Fz';
            app.FzGauge = uigauge(app.ForcesPanel, 'linear');
            app.FzGauge.Limits = [-1 1];
            app.ForwardLabel = uilabel(app.ForcesPanel);
            app.ForwardLabel.Text = 'Forward';
            app.BackwardLabel = uilabel(app.ForcesPanel);
            app.BackwardLabel.Text = 'Backward';
            app.RightLabel = uilabel(app.ForcesPanel);
            app.RightLabel.Text = 'Right';
            app.UpLabel = uilabel(app.ForcesPanel);
            app.UpLabel.Text = 'Up';
            app.DownLabel = uilabel(app.ForcesPanel);
            app.DownLabel.Text = 'Down';
            app.FyGaugeLabel = uilabel(app.ForcesPanel);
            app.FyGaugeLabel.Text = 'Fy';
            app.FyGauge = uigauge(app.ForcesPanel, 'linear');
            app.FyGauge.Limits = [-1 1];
            app.LeftLabel = uilabel(app.ForcesPanel);
            app.LeftLabel.Text = 'Left';
            app.MomentsPanel = uipanel(app.GaugesTab);
            app.MomentsPanel.Title = 'Moments';
            app.MxMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MyMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MzMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MMagGaugeLabel = uilabel(app.MomentsPanel);
            app.MMagGaugeLabel.Text = 'M Mag';
            app.MMagGauge = uigauge(app.MomentsPanel, 'linear');
            app.MzGaugeLabel = uilabel(app.MomentsPanel);
            app.MzGaugeLabel.Text = 'Mz';
            app.MzGauge = uigauge(app.MomentsPanel, 'linear');
            app.MzGauge.Limits = [-1 1];
            app.MxGaugeLabel = uilabel(app.MomentsPanel);
            app.MxGaugeLabel.Text = 'Mx';
            app.MxGauge = uigauge(app.MomentsPanel, 'linear');
            app.MxGauge.Limits = [-1 1];
            app.MyGaugeLabel = uilabel(app.MomentsPanel);
            app.MyGaugeLabel.Text = 'My';
            app.MyGauge = uigauge(app.MomentsPanel, 'linear');
            app.MyGauge.Limits = [-1 1];
            app.TurnLeftLabel = uilabel(app.MomentsPanel);
            app.TurnLeftLabel.Text = 'Turn Left';
            app.TurnRightLabel = uilabel(app.MomentsPanel);
            app.TurnRightLabel.Text = 'Turn Right';
            app.SBLeftLabel = uilabel(app.MomentsPanel);
            app.SBLeftLabel.Text = 'SB Left';
            app.SBRightLabel = uilabel(app.MomentsPanel);
            app.SBRightLabel.Text = 'SB Right';
            app.LookUpLabel = uilabel(app.MomentsPanel);
            app.LookUpLabel.Text = 'Look Up';
            app.LookDownLabel = uilabel(app.MomentsPanel);
            app.LookDownLabel.Text = 'Look Down';
            app.StopButton = uibutton(app.TargetDAQUI, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.TrialEditFieldLabel = uilabel(app.TargetDAQUI);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialNumberField = uieditfield(app.TargetDAQUI, 'numeric');
            app.TrialNumberField.Limits = [1 Inf];
            app.TrialNumberField.RoundFractionalValues = 'on';
            app.TrialNumberField.ValueDisplayFormat = '%.0f';
            app.TrialNumberField.ValueChangedFcn = createCallbackFcn(app, @TrialNumberFieldValueChanged, true);
            app.TrialNumberField.Value = 1;
            app.StatusLabel = uilabel(app.TargetDAQUI);
            app.StatusLabel.Text = 'Status: ';
            app.AcquireDataButton = uibutton(app.TargetDAQUI, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tDAQ

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetDAQUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetDAQUI)
        end
    end
end