classdef tDAQpy0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetDAQUI                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        DAQConfigurationPanel           matlab.ui.container.Panel
        DAQDeviceDropDownLabel          matlab.ui.control.Label
        DAQDeviceDropDown               matlab.ui.control.DropDown
        ScanRateLabel                   matlab.ui.control.Label
        scanRateField                   matlab.ui.control.NumericEditField
        SampleTimeLabel                 matlab.ui.control.Label
        sampleTimeField                 matlab.ui.control.NumericEditField
        SubjectInformationPanel         matlab.ui.container.Panel
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        ParticipantIDField              matlab.ui.control.EditField
        ProtocolEditFieldLabel          matlab.ui.control.Label
        ProtocolField                   matlab.ui.control.EditField
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesField                      matlab.ui.control.TextArea
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        EMGOptionsPanel                 matlab.ui.container.Panel
        CollectEMGCheckBox              matlab.ui.control.CheckBox
        ofEMGsSpinnerLabel              matlab.ui.control.Label
        nEMGSpinner                     matlab.ui.control.Spinner
        InitializeDAQButton             matlab.ui.control.Button
        FileOptionsPanel                matlab.ui.container.Panel
        CurrentDirectoryButton          matlab.ui.control.Button
        CurrentDirectoryTextArea        matlab.ui.control.TextArea
        basefilenameLabel               matlab.ui.control.Label
        basefilenameEditField           matlab.ui.control.EditField
        TransformTab                    matlab.ui.container.Tab
        BonyLandmarkTableLabel          matlab.ui.control.Label
        HeadFrameTable                  matlab.ui.control.Table
        XForwardYVerticalupZRightLabel  matlab.ui.control.Label
        WrenchFrameDropDownLabel        matlab.ui.control.Label
        WrenchFrameDropDown             matlab.ui.control.DropDown
        SetTargetTab                    matlab.ui.container.Tab
        TargetSettingsPanel             matlab.ui.container.Panel
        TargetDirectionLabel            matlab.ui.control.Label
        TargetTable                     matlab.ui.control.Table
        MagnitudeSliderLabel            matlab.ui.control.Label
        MagnitudeSlider                 matlab.ui.control.Slider
        OpenTargetWindowButton          matlab.ui.control.StateButton
        ZeroLoadCellButton              matlab.ui.control.Button
        ShowTargetCheckBox              matlab.ui.control.CheckBox
        RangesPanel                     matlab.ui.container.Panel
        WrenchLimitsTable               matlab.ui.control.Table
        WrenchRangeLabel                matlab.ui.control.Label
        TargetAxesLimitsTable           matlab.ui.control.Table
        VisualRangeLabel                matlab.ui.control.Label
        TargetModeDropDownLabel         matlab.ui.control.Label
        TargetModeDropDown              matlab.ui.control.DropDown
        WrenchPlotsTab                  matlab.ui.container.Tab
        WrenchAxes1                     matlab.ui.control.UIAxes
        WrenchAxes2                     matlab.ui.control.UIAxes
        WrenchAxes3                     matlab.ui.control.UIAxes
        WrenchAxes4                     matlab.ui.control.UIAxes
        WrenchAxes5                     matlab.ui.control.UIAxes
        WrenchAxes6                     matlab.ui.control.UIAxes
        EMGPlotsTab                     matlab.ui.container.Tab
        EMGAxes1                        matlab.ui.control.UIAxes
        EMGAxes2                        matlab.ui.control.UIAxes
        EMGAxes3                        matlab.ui.control.UIAxes
        EMGAxes4                        matlab.ui.control.UIAxes
        EMGAxes5                        matlab.ui.control.UIAxes
        EMGAxes6                        matlab.ui.control.UIAxes
        StopButton                      matlab.ui.control.Button
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialNumberField                matlab.ui.control.NumericEditField
        StatusLabel                     matlab.ui.control.Label
        AcquireDataButton               matlab.ui.control.Button
    end

    % FRAMES
    % Head body coordinate frame is oriented so that: 
    %       x points forward towards the nose, 
    %       y is vertical up
    %       z is to the right
    % 
    % World frame is at the center of the load cell oriented: 
    %       x points forward (LC z)
    %       y is vertical (LC Rz(-45deg))
    %       z points to the subjects right
    % 
    % 
    
    properties (Access = private)
         
        
        % Target Window Figure Handles
        FigureHandle                                    % handle to target figure
        AxesHandle                                      % handles to target axes
        
        % Patch objects 
        FBPyramidLH                                     % handle to feedback pyramid patch 
        TPyramidLH                                      % handle to target pyramid patch
        TargetTraceLH 
        GaugeObj = []                                   % vertical gauge object from lingauge class
        
        % Listener Handle
        DataAvailableLH                                 % listener handle for DAQ session 'DataAvailable' event
        
        % Transformation Matrices 
        CalMatrix = eye(6)                              % Load cell calibration matrix 
        LC2Head = eye(6)                                % Transform matrix from Load Cell
        LCZeroOffset = zeros(6,1)                       % load cell values from last time zero button pressed                  


        % Target and Feedback Pyramid Parameters 
        pyramid_width0 = 25                             % pyramid width at 0
        pyramid_ratio = 5                               % pyramid height/base size ratio
        pyramid_vertices                                % base pyramid vertices for patch        
        pyramid_faces                                   % base pyramid faces for patch
        basecolor = [0 0.4470 0.7410]                   % dark blue = [0 0.4470 0.7410]
        sidecolor = [0.9290 0.6940 0.1250]              % yellow = [0.9290 0.6940 0.1250]
        designcolor = [0.3010 0.7450 0.9330]            % light blue = [0.3010 0.7450 0.9330]
        neckcolor = [0 0.5 0.7]
        FBTransparency = 0.95                           % transparency of feedback pyramid faces
        TargetTransparency = 0.7                        % transparency of target pyramid faces [0 1] (0 = transparent)
        
        TargetAreaColor = 'y'
        TargetAreaLineColor = 'k'
        
        % Target Window Modes
        TargetMode                                      % Target visual mode from dropdown (1: Channel 2: Orientation and Mag 3: ...)
        FBFrameMode                                     % Frame of FB Wrench from dropdown (1: Head 2: LoadCell 3: World)
        
        % Target Setting Values
        TargetValues = zeros(6,1);                      % 1x6 array of target direction [fx fy fz mx my mz]
        
        % Target Window Visuals
        drawtarget = 0                                  % boolean whether or not to draw target
        
        % DAQ Configuration 
        DevicesInfo                                     % array of device information 
        daqDevice = 'Dev1'                              % default device name 
        sRate = 1000                                    % default sampling rate (Hz)
        sTime = 10                                      % default sampling length (sec)
        
        % 
        DAQSession = []                                 % daq session from s = daqinit()
        
        % Line Handles 
        WrenchLH = []                                   % array of Line handles
        EMGLH = []                                      % array of Line handles    
        
        
        % Range for visual feedback
        VisLim = [  0.25            1.75;       ...
                    -100            100;        ...
                    -100            100;        ...
                   deg2rad(-45)  deg2rad(45);   ...
                   deg2rad(-45)  deg2rad(45);   ...
                   deg2rad(-45)  deg2rad(45)]           % (6x2) (x,y,z,tx,ty,tz) x (min max)        
        
        % Wrench Range
        WRanges = [ -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30; ...
                    -30 30]                             % (6x2) (Fx,Fy,Fz,Mx,My,Mz) by (min max) in N or Nm
        
        
        % 
        fscale
        mscale
                    
        % Axes Handles
        WrenchAxes = []                                 % array of uiAxis handles 
        EMGAxes = []                                    % array of uiAxis handles 
        
        % Buffers
        TimestampsFIFOBuffer = []                       % Timestamps FIFO buffer of latest N sec
        RawDataFIFOBuffer = []                          % Raw voltage data from DAQ 
        WrenchFIFOBuffer = []                           % Wrench Data FIFO buffer (ch x n)
        EMGFIFOBuffer = []                              % EMG DATA FIFO buffer (ch x n)
        FIFOMaxSize = 1E+5                              % Max allowed buffer size
        
        % Configuration Information 
        Date = []                                       % saved date
        datadir = cd                                    % Description
        subjID = []                                     % 
        Protocol = []                                   % 
        Notes = []                                      %
        
        WrenchChannels = 0:5                            % daq channels for load cell inputs
        EMGChannelsAvail = 6:11                         % daq channels available for EMG inputs
        EMGChannels = []                                % daq channels for EMG inputs
        WrenchChannelNames = {'Fx','Fy','Fz','Mx','My','Mz'}
        EMGChannelNames = {'EMG1','EMG2','EMG3','EMG4','EMG5','EMG6'}
        
        % filepaths
        CalMatPath = jr3_loadcell.cal_mat                  % Calibration Matrix 
        
        % settings
        collectEMG = 0                                  % boolean: 0 = don't collect EMG; 1 = collect EMG
        
        itrial = 1                                      % 
        filename = 'data_trial'                         % 
        
        logdata = 0                                     % boolean: 0: don't save data; 1: save data to file
        starttime = []                                  % timestamp of first logged entry, set to empty if not logging
        TempFilename
        TempFile                                        % Temporary 
        TotalDataSaved                                  % Description


    end
    
    
    methods (Access = private)       
    
        % Transform data to new scale based on [min max] 
        function new_data = changeScale(~, data, iR, oR)
            % Inputs:       iR: (nx2) input Range n rows of [min max] 
            %               oR: (nx2) output Range n rows of [min max]
            %               data: (nx1) vector of data in input range (corresponding to [min max] in nth row)
            % Outputs:      new_data: (nx1) vector of data in output range
            % 
                       
            new_data = (oR(:,2)-oR(:,1))./(iR(:,2)-iR(:,1)).*(data - iR(:,1)) + oR(:,1);    
        end
        
        % (3x3) tranformation matrix to:
        %   reflect about a plane
        function R = ReflectAboutPlane(~,plane)
            % Inputs:       plane: 'xy', 'yz', or 'xz'
            % Outputs:      R : 3x3 matrix to reflect about plane 
            
            switch plane
                case 'xy'
                    dvec = [1 1 -1];
                case 'yz'
                    dvec = [-1 1 1];
                case 'xz'
                    dvec = [1 -1 1];
                otherwise 
                    dvec = [1 1 1];
                    warning('options for plane are xy yz and xz')
            end

            R = diag(dvec);
            
        end
        
        % (3x3) transformation matrix to:
        % rotate around arbitrary axis u (1x3) by th (scaler) in radians
        function R = RotateAxis(~, u, th)
            % u: unit vector, arbitrary axis to rotate around
            % th: angle in radians to rotate about u
            % R : 3x3 rot mat
            
            R = eye(3);
            nu = norm(u);
            eps = 0.01; % sensitivity for detecting rotation direction 
            if nu > eps % if not a zero vector
                % make sure it is a unit vector
                u = u/nu;
                % trig
                s = sin(th);
                c = cos(th);
                % Rodriguez formula
                for ii=1:3
                    v = R(:,ii);
                    R(:,ii) = v*c + cross(u,v)*s + u*dot(u,v)*(1-c);                      
                end                
            end          
        end
    
        function R = RotateVec2Vec(~, vec1, vec2)
            % 3D rotation matrix to align vec1 to vec2            
            
            % make sure they are unit vectors
            v1 = vec1/norm(vec1);
            v2 = vec2/norm(vec2);            
            
            if v1 == v2 
                % identity
                R = eye(3);
            else       
                % 
                v = cross(v1,v2);
                
                % skew-symmetric matrix 
                ssc = [ 0       -v(3)    v(2); ...
                        v(3)     0      -v(1); ...
                       -v(2)     v(1)    0  ];
                % compute rotation matrix 
                R = eye(3) + ssc + ssc^2*(1-dot(v1,v2))/(norm(v)^2);
            end
            
        end
    
        % homogenous (4x4) transformation matrix to: 
        % rotate by R (3x3) and translate by p (1x3) or (3x1)
        function Tmat = TransformMat(~,R,p)
            Tmat = [R [p(1) p(2) p(3)]'; [0 0 0 1]];
        end
    
        % Adjoint matrix to transform wrench coordinate frames
        function adT = adjointMatrix(~,R,p)
            % R: (3x3) rotation matrix 
            % p: (3x1) or (1x3) translation vector
            % adT: (6x6) adjoint matrix 
            % Wb = adT' * Wa % to change coordinates from a to b frame
            
            % skew-symmetric matrix 
            phat = [    0    -p(3)  p(2); ...
                        p(3)  0    -p(1); ...
                       -p(2)  p(1)  0  ];
        
            adT = [ R           phat*R   ; ...
                    zeros(3,3)  R       ];
        end
        
        % homogenous (4x4) transformation matrix to:
        % scale by s (scalar) uniformly about origin
        function Tmat = scaleMat(~,s)            
            Tmat = [s*eye(3) zeros(3,1); [0 0 0 1]];            
        end
    
        function pts = hom(~, pts) 
            % adds additional ROW of ones
            npts = size(pts,2);
            pts = [pts; ones(1,npts)];
        end
        
        function pts = unhom(~, pts)
            % removes last ROW
            pts = pts(1:3,:);
        end
    
        % vertices and faces for a pyramid 
        % to be used to create a patch object
        function [v, f, c] = pyramidneck(~, pdim, nw, tdim, ymin, fcolors)
            % pdim: [nose length, head height, head width]
            % nw: neck width
            % tdim: [torso height, torso width]
            % ymin: min ylim 
            % fcolors: [base; side; design; neck/torso]
            % v : vertices (x, y, z)
            % f : faces by vertex index 
            % c : array of face colors by face number
        
            px = pdim(1);           % nose length
            py = pdim(2);           % head height
            pz = pdim(3);           % head width
            nz = nw/2;              % neck width 
            ty = ymin + tdim(1);    % torso height
            tz = tdim(2)/2;         % torso width
            
        
            %pyramid vertices (8) (need to transpose for hom, unhom, and computations)
            v =     [    0    py   -pz/2 ;... %1 % head pyramid
                         0    py    pz/2 ;... %2
                         0    0     pz/2 ;... %3
                         0    0    -pz/2 ;... %4
                         px   py/2    0  ;... %5
                         0    py/2    0  ;... %6
                         0    0     -nz  ;... %7 % neck
                         0    0      nz  ;... %8
                         0    ty     nz  ;... %9 % do not transform from here on
                         0    ty    -nz  ;... %10
                         0    ty    -tz  ;... %11 %torso
                         0    ty     tz  ;... %12
                         0    ymin   tz  ;... %13
                         0    ymin  -tz  ];...%14


            % pyramid faces (8)
            f = [   1  6  2  1  ; ...% base top
                    2  6  3  2  ; ...% base right
                    3  6  4  3  ; ...% base bottom
                    4  6  1  4  ; ...% base left
                    1  5  2  1  ; ...% side top
                    2  5  3  2  ; ...% side right
                    3  5  4  3  ; ...% side bottom
                    4  5  1  4  ; ...% side left 
                    7  8  9  10 ; ...% neck
                   11 12 13  14 ];...% torso
            
            c = [ repmat(fcolors(1,:), 1,1); ... % design color
                  repmat(fcolors(2,:), 3,1); ... % base color
                  repmat(fcolors(3,:), 4,1); ... % side color
                  repmat(fcolors(4,:), 2,1) ];   % neck and torso     


        end


        % update or create patch object of pyramid using 6x1 vec (x,y,z,thx,thy,thz) 
        function lh = updatePyramid(app, lh, p, r, id) 
            % lh: patch object 
            % p: (3x1) or (1x3) linear components
            % f: (3x1) or (1x3) rotational components
            
            
            % if the line handle has not been created
            if isempty(lh)
                vlim = app.VisLim; %(x,y,z,tx,ty,tz) by (min max) %(6x2)
                % size
                
                % fcolors: [base; side; design; neck/torso]
                fcolors = [app.basecolor; app.sidecolor; app.designcolor; app.neckcolor];
                % pdim: [nose length, head height, head width]
                pybase = app.pyramid_width0;
                pyheight = pybase*app.pyramid_ratio;
                pdim = [pyheight, 2*pybase, pybase];
                % nw: neck width
                nw = pybase/3;
                % tdim: [torso height, torso width]
                torso_height = (vlim(2,2) - vlim(2,1))/10;
                torso_width = (vlim(3,2)-vlim(3,1))/2;
                tdim = [torso_height, torso_width];
                % ymin: min y-axis limit 
                ymin = vlim(2,1);
                
                % get vertices, faces, and colors for base pyramid at [0, 0, 0, 0, 0, 0]
                [app.pyramid_vertices, app.pyramid_faces, c] = app.pyramidneck(pdim, nw, tdim, ymin, fcolors);

                switch id
                    case {'fb',1}
                        tval = app.FBTransparency;
                    case {'target',2}
                        tval = app.TargetTransparency;
                    otherwise
                        tval = 1;
                end
                
                % create patch 
                lh = patch('Parent', app.AxesHandle(1)...
                    ,'Vertices', app.pyramid_vertices, 'Faces', app.pyramid_faces...
                    ,'FaceColor', 'flat', 'FaceVertexCData', c...
                    , 'FaceAlpha', tval);                 
                
            end
            
            % vertices of base pyramid
            vp = app.pyramid_vertices(1:8,:); % transform these points
            vn = app.pyramid_vertices(9:14,:); % don't transform these points (torso and lower neck)
            
            %temp
            fbmode = 1;
            switch fbmode % this may not belong here.. 
                case {1,'1',3,'3'} % linear (match, trace)
                    % Transform Matrices
                    mvec = r;
                    mmag = norm(r);
                    fvec = p;
 
                case {2,'2'} % direction and mag
                    [fu, mu, fmr] = app.W2FMDir([p;r]);
                    
                    if fmr > 1 % limited by force
                        fvec = app.fscale .* fu;
                        mmag = app.mscale/fmr;
                        
                    else % fmr <=1 % limited by moment
                        mmag = app.mscale;
                        fvec = fmr * app.fscale .* fu;
                    end                    
                    
                    mvec = mu;                   
               
                otherwise % side by side? 
            
            end
            
            % Transform Matrices
            R = app.RotateAxis(mvec, mmag);
            gR = app.TransformMat(R, [0;fvec(2:3)]);
            gx = app.scaleMat(fvec(1));
            Tmat = gR*gx;
            
            % transform original pyramid
            v = app.unhom(Tmat*app.hom(vp'))';
            
            % set patch object handle data to these new points
            lh.Vertices = [v; vn];
         
        end  
    
        function updateTarget(app, mode)
            % only execute if figure window is open and show target checkbox is checked
            if all([~isempty(app.FigureHandle),app.drawtarget])
                % scale target values
                tvec = app.changeScale(app.TargetValues, app.WRanges, app.VisLim);                
                
                % target visual mode 
                switch mode
                    case {1,'1'} % Target Match 
                        app.TPyramidLH = app.updatePyramid(app.TPyramidLH, tvec(1:3), tvec(4:6),'target');
                    case {2,'2'} % Orientation and Mag
                                           
                    case {3, '3'} % Trace
                        app.updateTargetTrace(tvec); 
                    case {4, '4'} % side by side
                        app.TPyramidLH = app.updatePyramid(app.TPyramidLH, tvec(1:3), tvec(4:6),'target');
                    otherwise
                end
            end
        
            
        end
    
        function [fu, mu, fmratio] = W2FMDir(~, Wvec)
           % Wvec: (6x1) or (1x6) vector of wrench (fx, fy, fz, mx, my, mz)
           % fu: (3x1) or (1x3) unit force vector (fx, fy, fz)
           % mu: (3x1) or (1x3) unit moment vector (mx, my, mz)
           % fmratio: force/moment magnitude ratio
           fvec = Wvec(1:3);
           mvec = Wvec(4:6);          
           fmag = norm(fvec);
           mmag = norm(mvec);
           
           if mmag ~= 0               
               mu = mvec/mmag;
               fmratio = fmag/mmag; 
           else % fmag = 0 % avoid division by zero
               mu = mvec;
               fmratio = fmag; %?
           end
               
           if fmag ~= 0
                fu = fvec/fmag;
           else % fmag = 0 % avoid division by zero
               fu = fvec;
           end
           
           
        end
    
        function updateTargetTrace(app, dir)
           % THIS ISN'T WORKING YET
           [fu, mu, fmratio] = app.W2FMDir(dir); 
           
           ntraces = 10;
           mult = 0.1 : magmax/ntraces : magmax;
           traces = mult * [fmratio * fdir; mu];   


           % rotation matrix about x (in y-z plane)
           R = app.RotateVec2Vec([1 0 0]', [0 uxyz(2) uxyz(3)]');
           
           % rotate cone to align with [x y] target direction         
           rot_pts = R * pts';
           
           % create patch obj if it doesn't exist
           if isempty(app.TargetTraceLH)
               for tt = 1 : ntraces
               end
                app.TargetTraceLH = patch('Parent',app.AxesHandle(1));
                app.TargetTraceLH.Faces = [1 2 3 4 1]; 
                app.TargetTraceLH.FaceColor = app.TargetAreaColor;
                app.TargetTraceLH.EdgeColor = app.TargetAreaLineColor;
                app.TargetTraceLH.FaceAlpha = 0.1;
           end
           
           % update vertices           
           app.TargetTraceLH.Vertices = rot_pts'; 
        end
        
        % left hand coordinate frame :(
        % Compute Coordinate Transform for Load cell Frame -> World Frame -> Head Frame
        function gT = computeCoordinateTransform(app, p, th)
            %   p: translation vector (x,y,z) from world frame to head frame 
            %   th: vector of angles about each axis (thX, thY, thZ) to rotate in order z-y-x
            %   gT: adjoint matrix to transform wrench from load cell to head frame coordinates
            
            % Load Cell Coordinate Frame: located at center of load cell 
            %       x: away from label
            %       y: toward cable connection perp to x
            %       z: forward (out from sensor side)
            % (R-Hand coordinate frame from robot side, L-Hand from sensor side)
            
            % World Coordinate Frame: located at center of load cell 
            %       x:  forward (horizontal to ground)
            %       y:  vertical up (parallel to gravity) 
            %       z:  to subject's right (horizontal to ground)
            % 
            
            % Head Coordinate Frame: located at C2 dens
            %       x:  forward
            %       y:  vertical up
            %       z:  to subject's right          
            % 
            
            
            % Transform load cell to world frame

            % Reflection about xy plane  
            Rr = app.ReflectAboutPlane('xy');
            % rotate 90 deg about y axis 
            Ry = app.RotateAxis([0 1 0]',deg2rad(-90));
            %p = [0.1 0 0]; % move .1 m in x direction of world frame
            p0 = zeros(1,3);

            A_LC2W = app.adjointMatrix(Ry*Rr,p0);
                       
            
            switch app.FBFrameMode
                case {1, '1', 'Hframe'} % head frame
                    % create rotation matrices about each axis and translation vector             
                    Rx = app.RotateAxis([1 0 0]', th(1));
                    Ry = app.RotateAxis([0 1 0]', th(2));
                    Rz = app.RotateAxis([0 0 1]', th(3));                    
                    % adjoint matrix from world frame to head frame
                    A_W2H = app.adjointMatrix(Rx*Ry*Rz, p);                    
                    % adjoint matrix from load cell to head frame
                    gT = A_LC2W * A_W2H;
                case {2, '2', 'Wframe'} % world frame
                    gT = A_LC2W;
                case {3, '3', 'LCframe'} % load cell frame
                    gT = eye(6);
                otherwise % not transformed
                    gT = eye(6);
            end                   
            
        end
    
        
        function new_value = deleteHandle(~,handle)
            % delete handle and set value to empty
            if ~isempty(handle)
                for ii = 1 : length(handle) % single or list of handles
                    if ishandle(handle(ii))
                        delete(handle(ii))
                    end                    
                end
                new_value = []; % set handle to empty
            else % if already empty
                new_value = handle; % pass through
            end
            
        end
    
        
        function new_value = closeFigure(~,handle)
            % close figure window and delete handle
            if ~isempty(handle)
                if ishandle(handle)
                    close(handle) % executes figure's CloseRequestFcn
                end
                new_value = [];
            else
                new_value = handle;
            end         
            
        end 
    
        function lh = clearLine(~, lh)
            % This clears the data in the line handle but does not delete the handle 
            for ii = 1 : length(lh)
                if ishandle(lh(ii))
                    set(lh(ii), 'XData', [],'YData', [])
                end
            end
        end
        
        function initializeTargetWindow(app)
            
            % delete handles if they already exist
            app.FigureHandle = app.deleteHandle(app.FigureHandle);
            app.AxesHandle = app.deleteHandle(app.AxesHandle);
            
            % Create figure
            app.FigureHandle = uifigure('Name','Target DAQ'...
                ,'AutoResizeChildren','off');
            
            axlims = app.adjustAxesLimits(app.VisLim, app.pyramid_width0, 2*app.pyramid_width0); 
            
            % target visual mode
            switch app.TargetMode
               
                case {1, '1', 3, '3'} % match or trace
                    app.AxesHandle = uiaxes(app.FigureHandle...
                        ,'XTick',[],'YTick',[],'ZTick',[] ...
                        ,'XTickLabel',{},'YTickLabel',{},'ZTickLabel',{} ...
                        ,'XLim',axlims(1,:), 'YLim',axlims(2,:), 'ZLim',axlims(3,:) ...
                        ,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1] ...
                        ,'YAxisLocation', 'origin'...
                        , 'CameraPosition', [-100 0 0], 'CameraUpVector', [0 1 0]);

                case {2, '2'} % dir and mag
                    % ax1 is wrench direction feedback
                    % ax2 is vertical linear gauge for wrench magnitude feedback
                    app.AxesHandle(1) = subplot(1,2,1, 'Parent', app.FigureHandle);
                    app.AxesHandle(2) = subplot(1,2,2, 'Parent', app.FigureHandle);
                    
                    % set parameters for wrench direction feedback axis 
                    set(app.AxesHandle(1)...
                        , 'XTick',[],'YTick',[],'ZTick',[] ...
                        ,'XTickLabel',{},'YTickLabel',{},'ZTickLabel',{} ...
                        ,'XLim',axlims(1,:), 'YLim',axlims(2,:), 'ZLim',axlims(3,:) ...
                        ,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1] ...
                        ,'YAxisLocation', 'origin'...
                        , 'CameraPosition', [-100 0 0], 'CameraUpVector', [0 1 0]);
                    
                    % get position and dimensions for gauge axis
                    pos1 = get(app.AxesHandle(1),'Position');
                    optp.loc = [pos1(1) + pos1(3), pos1(2)];
                    optp.dim = [pos1(3)/4, pos1(4)];
                    
                    % create gauge object
                    app.GaugeObj = lingauge(app.AxesHandle(2), optp);
                    
                   
                case {4, '4'} % side by side                    
                    nax = 2;
                    for aa = 1 : nax
                        app.AxesHandle(aa) = subplot(1,nax,aa);
                        set(app.AxesHandle(aa) ...
                            ,'XTick',[],'YTick',[],'ZTick',[] ...
                            ,'XTickLabel',{},'YTickLabel',{},'ZTickLabel',{} ...
                            ,'XLim',axlims(1,:), 'YLim',axlims(2,:), 'ZLim',axlims(3,:) ...
                            ,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1] ...
                            ,'YAxisLocation', 'origin');
                        view(app.AxesHandle(aa),[-1 0 0]);  
                    end 
            end            
            
        end
    
        function axlims = adjustAxesLimits(~, vislim, basewidth0, baseht0)
            % vislim: 6x2 (x,y,z,mx,my,mz) x (min max)
            % axlims: 3x2 (x,y,z) x (min max)
            
            xmax = vislim(1,2);
            zoffset = xmax * basewidth0 / 2;
            yoffset = xmax * baseht0;            
             
            xlimits = [-200 200];
            ylimits = vislim(2,:) + [-yoffset yoffset]; % torso and neck height?
            zlimits = vislim(3,:) + [-zoffset zoffset];
            
            axlims = [xlimits; ylimits; zlimits];           
            
        end
      
        function lh = initializePlots(~, ax, ch, ch_names)
            % ax = app.WrenchAxes or app.EMGAxes; % (1 x #ch) array of UIAxis handles
            % ch = app.WrenchChannels or app.EMGChannels; % (1 x #ch) array of daq channels
            % lh : (1 x #ch) array of line handles 
            
            nCh = length(ch);
            lh = [];                      
                
            % Create a line plot and store its handle
            for ii = 1 : nCh
                title(ax(ii),ch_names(ii))
                lh(ii) = plot(ax(ii), NaN, NaN);                
            end
           
        end    

    
        function data = storeDataInFIFO(~, data, buffersize, datablock)
            % storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
            %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
            %  a smooth live plot update and without continuously increasing memory use.
            %  The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the 
            %  buffer exceeds the specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of
            %  the data in the buffer to BUFFERSIZE.
            %  input DATA is the existing data buffer (array nchan x N).
            %  BUFFERSIZE is the desired buffer size (maximum number of columns in DATA buffer) and can be changed.
            %  DATABLOCK is a new data block to be added to the buffer (array nchan x K).
            %  output DATA is the updated data buffer (array nchan x M).
            
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
                
            % length of data and data block (number of samples)
            ndata = size(data,2);
            ndatablock = size(datablock,2);
            
            if ndata > buffersize
                data = data(:,end-buffersize+1:end);
            end
            
            if ndatablock < buffersize
                % Data block size (number of columns) is smaller than the buffer size
                if ndata == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = ndatablock;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,end-shiftPosition+1:end) = datablock;
                elseif (ndata < buffersize) && (ndata + ndatablock > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data datablock];
                    shiftPosition = ndata - buffersize;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,buffersize+1:end) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(:,end-buffersize+1:end);
            end            
        end

        function DataAvailableCallback(app, ~, event)
            % dataAvailable_Callback Executes on DAQ session DataAvailable event
            %  This callback function gets executed periodically as more data is acquired.
            %  event.TimeStamps (nx1) and event.Data (n x nch)         
            
            % organize data and store in FIFO buffers 
            app.RawDataFIFOBuffer = app.storeDataInFIFO(app.RawDataFIFOBuffer, app.FIFOMaxSize, event.Data');
            
            timestamps = event.TimeStamps'; % make (1 x #scans)
            app.TimestampsFIFOBuffer = app.storeDataInFIFO(app.TimestampsFIFOBuffer, app.FIFOMaxSize, timestamps);
            
            % transform wrench data to head frame
            wrenchdata = app.LC2Head' * app.CalMatrix * (event.Data(:,app.WrenchChannels+1)' - app.LCZeroOffset); % (#ch x #scans)
            app.WrenchFIFOBuffer = app.storeDataInFIFO(app.WrenchFIFOBuffer, app.FIFOMaxSize, wrenchdata);
            
            if ~isempty(app.EMGChannels)
                emgdata = event.Data(:,app.EMGChannels+1)'; % (#ch x #scans)
                app.EMGFIFOBuffer = app.storeDataInFIFO(app.EMGFIFOBuffer, app.FIFOMaxSize, emgdata);
            else
                emgdata = [];
            end
            
            % log data
            if app.logdata
                
                if isempty(app.starttime)
                    % set start time to first time stamp 
                    app.starttime = timestamps(1); 
                    app.TotalDataSaved = 0;
                end               
                
                % save to temp file 
                fwrite(app.TempFile, squeeze([timestamps;wrenchdata;emgdata]), 'double');
                
                % update count of data (used to keep track of time for end condition)
                app.TotalDataSaved = app.TotalDataSaved + length(timestamps);
                
                % check ending condition
                if app.TotalDataSaved >= app.sTime * app.sRate
                    % stop logging data
                    app.logdata = 0;
                    
                    % close temp file and save to .mat file 
                    app.saveDataFile(app.starttime, timestamps(end), app.TotalDataSaved);
                    
                    % enable buttons now that finished acquiring
                    app.EnableDisableAcquisition(1)
                    
                    % reset 
                    app.starttime = [];
                    app.TotalDataSaved = 0;  
                    
                    % update trial number for next trial 
                    app.itrial = app.itrial + 1;
                    app.TrialNumberField.Value = app.itrial;
                end             
                
            end
            
            % Update plot data if in current view
            if app.TabGroup.SelectedTab == app.WrenchPlotsTab
                app.updatePlots(app.WrenchAxes, app.WrenchLH, app.TimestampsFIFOBuffer, app.WrenchFIFOBuffer);
            end
            if all([app.TabGroup.SelectedTab == app.EMGPlotsTab, app.collectEMG, ~isempty(app.EMGChannels)])
                app.updatePlots(app.EMGAxes, app.EMGLH, app.TimestampsFIFOBuffer, app.EMGFIFOBuffer);
            end            
            
            % update target window if open too 
            if ishandle(app.FigureHandle)
                % transform wrench data for visual feedback in target display
                meandata = mean(wrenchdata,2);
                fbdata = app.changeScale(meandata, app.WRanges, app.VisLim); % (6x6) -> (6x1)
                app.FBPyramidLH = app.updatePyramid(app.FBPyramidLH, fbdata(1:3), fbdata(4:6), 'fb');            
                
            end          
        end        

    
        function [t,d] = binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,numColumns-1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), :) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
            
            % assign outputs 
            t = matObj.timestamps;
            d = matObj.data;
        end
    
    
        function saveDataFile(app, time0, timef, nScans)            
            
            % Close temporary binary file
            fclose(app.TempFile);           
            
            % Gather metadata in preparation for saving to MAT file
            % Store relevant Daq device info
            metadata = app.setupStruct();
            metadata.trialnum = app.itrial;  
            metadata.time0 = time0;
            metadata.timef = timef;
            metadata.nScans = nScans;           
            
            % add trial number to filename
            [~, fn, ~] = fileparts(app.filename);            
            new_fn = [fn '_' num2str(app.itrial) '.mat'];
            matFilepath = fullfile(app.datadir, new_fn);
            % if file exists, add _N to end until unique
            matFilepath = app.uniqueFileName(matFilepath, '.mat');
            
            app.StatusLabel.Text = 'Saving data to MAT file is in progress...';
            drawnow
            
            numColumns = length(squeeze([app.WrenchChannels app.EMGChannels])) + 1; %nchannels + 1 for timestamps
            
            % Convert data from binary file to MAT file
            [t,d] = app.binFile2MAT(app.TempFilename, matFilepath, numColumns, metadata);
            
            % take just the filename for status update
            [~, fn, ext] = fileparts(matFilepath); 
            app.StatusLabel.Text = sprintf('Saving data to ''%s'' file has completed.', [fn ext]);
            
            % plot data from logged file to new figure window
            app.plotDataToNewWindow(t,d(:,1:6)',app.WrenchChannelNames);
            
            if ~isempty(app.EMGChannels)
                app.plotDataToNewWindow(t,d(:,7:end)',app.EMGChannelNames);
            end
            
        end
    
        function [f, ax] = plotDataToNewWindow(~, timestamps, data, chnames)
            
            f = figure('Name', 'Wrench Data');            
            
            % dimensions
            nch = size(data,1);
            ncols = 2;
            nrows = ceil(nch/ncols);
            
            % create subplots and save axes
            for ii = 1 : nch                
                ax(ii) = subplot(nrows,ncols,ii);
                plot(ax(ii), timestamps, data(ii,:))
                if nargin > 3
                    title(ax(ii), chnames(ii))
                else
                    title(ax(ii), num2str(ii))
                end
            end
            
        end
            

        function updatePlots(~, ax, lh, xdata, ydata)
            % ax = app.WrenchAxes or app.EMGAxes; %(1 x #Axes) array of UIAxis handles
            % xdata = app.TimestampsFIFOBuffer; % (1 x #scans) array
            % ydata = app.WrenchFIFOBuffer or app.EMGFIFOBuffer; % (#ch x #scans) array
            
            % set axis data objects
            if numel(xdata) > 1
                for ii = 1 : length(lh)
                    if ishandle(lh(ii))
                        set(lh(ii), 'XData', xdata, 'YData', ydata(ii,:));
                    end
                    if ishandle(ax(ii))
                        xlim(ax(ii), [xdata(1), xdata(end)]); 
                    end                        
                end
            end
            
        end    
       
        function cal_mat = loadCalMatrix(~,mat)

            if ismatrix(mat) 
                cal_mat = mat;               
            else 
                % If error, just use identity matrix is being used 
                cal_mat = eye(6);
                warndlg('Could not find calibration matrix. Using identity matrix instead.','Calibration Matrix');
            end

            
        end
        
        function closeApp_Callback(app, ~, event, isAcquiring)
            % closeApp_Callback Executes after "Close Confirm" dialog window
            %  "Close Confirm" dialog window is launched from DataAcquisitionLiveCloseRequest.
            %   before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   DAQ session and close file if logging.
            
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession);
                        app.deleteHandle(app.DataAvailableLH);
                        app.deleteHandle(app.DAQSession);
                    else
                        % Acquisition is stopped
                    end
                    
                    % Re-enable warning when session rate is adjusted to closest supported sampling rate
                    warning('on', 'daq:Session:closestRateChosen')
                    delete(app);
                case 'Cancel'
                    % Continue
            end
            
        end    
        
    
        function EnableDisableInitialization(app, bool)
           
            switch bool 
               
                case 0 % DAQ initialized and Background Collection Running                  
                    app.InitializeDAQButton.Enable = 'off';
                    app.scanRateField.Enable = 'off';
                    app.CollectEMGCheckBox.Enable = 'off';
                    app.nEMGSpinner.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.DAQDeviceDropDown.Enable = 'off';
                    app.AcquireDataButton.Enable = 'on';
                    app.LoadConfigButton.Enable = 'off';
                    
               
               case 1 % DAQ stopped and Cleared; not running 
                    app.InitializeDAQButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.scanRateField.Enable = 'on';
                    app.CollectEMGCheckBox.Enable = 'on';
                    app.nEMGSpinner.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.AcquireDataButton.Enable = 'off';
                    app.DAQDeviceDropDown.Enable = 'on';
                    app.LoadConfigButton.Enable = 'on';
                    
           end
        end
    
        function EnableDisableAcquisition(app, bool)
            
            switch bool
                case 0 % currently acquiring 
                    app.StatusLabel.Text = 'Status: Acquiring Data';
                    app.AcquireDataButton.Enable = 'off';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.sampleTimeField.Enable = 'off';
                case 1 % not acquiring data
                    app.AcquireDataButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.sampleTimeField.Enable = 'on';
            end
        end      


        function setup = setupStruct(app)
            % app property values to save into setup structure 

            setup.subject = struct(  'Date',                 date,                    ...
                                     'subjID',               app.subjID,              ...
                                     'Protocol',             app.Protocol,            ...
                                     'Notes',                app.Notes);
            setup.daq = struct(      'DevicesInfo',          app.DevicesInfo,         ...
                                     'WrenchChannels',       app.WrenchChannels,      ...
                                     'EMGChannels',          app.EMGChannels,         ...
                                     'WrenchChannelNames',  {app.WrenchChannelNames}, ...
                                     'EMGChannelNames',     {app.EMGChannelNames},    ...
                                     'sRate',                app.sRate,               ...
                                     'sTime',                app.sTime);
            setup.target = struct(   'TargetValues',         app.TargetValues,        ...
                                     'VisLim',               app.VisLim,              ...
                                     'WRanges',              app.WRanges);   
            setup.params = struct(   'LC2Head',              app.LC2Head,             ...
                                     'CalMatrix',            app.CalMatrix);            
            
        end
    
        function value = ifisfield(~, s, fstr, cval)
            % only assign value from struct if fstr is a field of the struct s
            % otherwise keep current value cval
            if isfield(s,fstr)
                value = s.(fstr);
            else
                value = cval;
            end
        end
    
        function loadConfigUpdate(app, setup)
            
            % LOAD VALUES FROM SETUP STRUCT
            % setup.subject
            s = setup.subject;
            app.subjID = app.ifisfield(s,'subjID',app.subjID);
            app.Protocol = app.ifisfield(s,'Protocol',app.Protocol);
            app.Notes = app.ifisfield(s,'Notes',app.Notes);
            % setup.daq
            s = setup.daq;
            app.WrenchChannels = app.ifisfield(s,'WrenchChannels',app.WrenchChannels);
            app.EMGChannels = app.ifisfield(s,'EMGChannels',app.EMGChannels);
            app.WrenchChannelNames = app.ifisfield(s,'WrenchChannelNames',app.WrenchChannelNames);
            app.EMGChannelNames = app.ifisfield(s,'EMGChannelNames',app.EMGChannelNames);
            app.sRate = app.ifisfield(s,'sRate',app.sRate);
            app.sTime = app.ifisfield(s,'sTime',app.sTime);
            % add nEMG
            % setup.target
            s = setup.target;
            app.TargetValues = app.ifisfield(s,'TargetValues',app.TargetValues);
            app.VisLim = app.ifisfield(s,'VisLim',app.VisLim);
            app.WRanges = app.ifisfield(s,'WRanges',app.WRanges);
            % setup.params
            s = setup.params;
            app.LC2Head = app.ifisfield(s,'LC2Head',app.LC2Head);           
            app.CalMatrix = app.ifisfield(s,'CalMatrix',app.CalMatrix);
            
            % UPDATE UI COMPONENTS            
            % subject
            app.ParticipantIDField.Value = app.subjID;
            app.ProtocolField.Value = app.Protocol;
            app.NotesField.Value = app.Notes;
            % daq
            app.scanRateField.Value = app.sRate;
            app.sampleTimeField.Value = app.sTime;
            % add nEMGspinner
            % target
            app.TargetTable.Data = app.TargetValues;
            app.TargetAxesLimitsTable.Data = [app.VisLim(1:3,:); rad2deg(app.VisLim(4:6,:))];
            app.WrenchLimitsTable.Data = app.WRanges;            
            
        end
        
        function fullName = uniqueFileName(~, fullName, ext)
            % fullName includes the path (relative or not)
            % ext is the extension (ie '.mat')
            [pn, fn, ~] = fileparts(fullName);
            
            iter = 0;
            while isfile(fullName) % file exists
                iter = iter + 1;
                new_fn = [fn '_' num2str(iter) ext];
                fullName = fullfile(pn, new_fn);
            end
            
        end
    
        function initializeAppVars(app)
            
            % get calibration matrix 
            app.CalMatrix = app.loadCalMatrix(app.CalMatPath);
            
            % set to default values from ui control
            app.drawtarget = app.ShowTargetCheckBox.Value;
            app.collectEMG = app.CollectEMGCheckBox.Value;
            
            app.sRate = app.scanRateField.Value;
            app.sTime = app.sampleTimeField.Value;
            
            app.subjID = app.ParticipantIDField.Value;
            app.Protocol = app.ProtocolField.Value;
            app.Notes = app.NotesField.Value;      
            
            app.TargetMode = str2num(app.TargetModeDropDown.Value);
            app.FBFrameMode = str2num(app.WrenchFrameDropDown.Value);
                        
            % uiAxes Handles
            app.WrenchAxes = [app.WrenchAxes1,app.WrenchAxes2,app.WrenchAxes3,...
                app.WrenchAxes4,app.WrenchAxes5,app.WrenchAxes6];
            app.EMGAxes = [app.EMGAxes1,app.EMGAxes2,app.EMGAxes3,...
                app.EMGAxes4,app.EMGAxes5,app.EMGAxes6];            
                
            % Target Axes 
            app.TargetAxesLimitsTable.Data = [app.VisLim(1:3,:); rad2deg(app.VisLim(4:6,:))]; 
            app.WrenchLimitsTable.Data = app.WRanges;
            
            % Target Values
            app.TargetTable.Data = app.TargetValues;
            
            % update adjoint matrix to change coordinates from load cell -> head frame
            W2LCdefault = [5 -5 0 0 0 0]';
            app.HeadFrameTable.Data = W2LCdefault;
            app.LC2Head = app.computeCoordinateTransform(W2LCdefault(1:3),W2LCdefault(4:6));


        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this is run at start up 
            
            % Detect all connected devices
            devices = daq.getDevices;
            
            % Store DAQ device information into DevicesInfo app property
            app.DevicesInfo = devices;
            
            % populate the device drop down list with cell array of composite device names (ID + model) 
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            app.DAQDeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DAQDeviceDropDown.ItemsData = 1:numel(devices)+1;  
            
            % initialize app. properties 
            app.initializeAppVars();                       
            
            % Current data directory
            app.CurrentDirectoryTextArea.Value = app.datadir;


        end

        % Value changed function: OpenTargetWindowButton
        function OpenTargetWindowButtonValueChanged(app, event)
            targetOpen = app.OpenTargetWindowButton.Value;
            
            switch targetOpen
                
                case 0 % Close Figure and Reset Objects
                    
                    % Change button text 
                    app.OpenTargetWindowButton.Text = 'Open Target Window';
                    
                    % Close Figure and delete handle 
                    app.FigureHandle = app.closeFigure(app.FigureHandle);
                    
                    % Delete other Handles 
                    app.AxesHandle = app.deleteHandle(app.AxesHandle);                    
                    app.FBPyramidLH = app.deleteHandle(app.FBPyramidLH);
                    app.TPyramidLH = app.deleteHandle(app.TPyramidLH);
                    
                    % reset checkboxes and disable 
                    app.ShowTargetCheckBox.Value = 0;
                    app.ShowTargetCheckBox.Enable = 'off';
                    app.drawtarget = 0;
                    
                    % re-enable changing mode 
                    app.TargetModeDropDown.Enable = 'on';
                    
                    
                case 1 % Open Figure and Initialize Objects
                    
                    % Change button text 
                    app.OpenTargetWindowButton.Text = 'Close Target Window';
                    
                    % open figure and axes for wrench feedback 
                    app.initializeTargetWindow();                                     
                   
                    % Enable checkboxes
                    app.ShowTargetCheckBox.Value = 0;                    
                    app.ShowTargetCheckBox.Enable = 'on';   
                    app.drawtarget = 0;    
                    
                    % disable changing mode 
                    app.TargetModeDropDown.Enable = 'off';
                    
            end
            
        end

        % Cell edit callback: TargetTable
        function TargetSetValueChanged(app, event)
            
            % update target settings property                        
            app.TargetValues = app.TargetTable.Data;             
                       
            % update target
            app.updateTarget(app.TargetMode);                            

            
        end

        % Button pushed function: ZeroLoadCellButton
        function ZeroLoadCellButtonPushed(app, event)
            if ~isempty(app.DAQSession) % daq is initialized
                if app.DAQSession.IsRunning  % daq is acquiring data
                    if ~isempty(app.RawDataFIFOBuffer) % has acquired at least a some data
                        % set app property to current load cell data 
                        cdata = app.RawDataFIFOBuffer(app.WrenchChannels+1,:);
                        amt = size(cdata,2);
                        if amt > 50
                            LCData = mean(cdata(:,end-50:end),2);
                        else
                            LCData = mean(cdata,2);
                        end                        
                        app.LCZeroOffset = LCData;
                        
                        debug = 0;
                        if debug == 1
                            disp('LCOffset = ')
                            disp(LCData)
                        end
                        
                    end
                end

            end           

        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            
            deviceIndex = app.DAQDeviceDropDown.Value - 1;
            
            if deviceIndex > 0
                
                % disable/enable Buttons 
                app.EnableDisableInitialization(0);
                
                % Delete DAQ objects if they already exist to avoid errors            
                app.DAQSession = app.deleteHandle(app.DAQSession);    
                app.DataAvailableLH = app.deleteHandle(app.DataAvailableLH);
                
                % Get the device information needed
                deviceID = app.DevicesInfo(deviceIndex).ID;
                vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                
                % Create a new DAQ session
                app.DAQSession = daq.createSession(vendor);
                measurementType = 'Voltage';
                addAnalogInputChannel(app.DAQSession, deviceID, [app.WrenchChannels app.EMGChannels], measurementType);
                
                % Set DAQ object sampling rate and time
                app.DAQSession.Rate = app.sRate;
                app.DAQSession.IsContinuous = true;
                app.DAQSession.NotifyWhenDataAvailableExceeds = 0.1*app.sRate; %CHECK THIS
                
                % Create listener for DataAvailable event and specify callback function
                app.DataAvailableLH = addlistener(app.DAQSession,'DataAvailable', ...
                    @(src,event) DataAvailableCallback(app, src, event));                              
                
                % reset FIFO buffer data
                app.WrenchFIFOBuffer = [];
                app.EMGFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
                
                % start collecting data in background (continuous mode)
                startBackground(app.DAQSession); 
                
                % initialize plots
                app.WrenchLH = app.initializePlots(app.WrenchAxes, app.WrenchChannels+1, app.WrenchChannelNames);
                
                if app.collectEMG == 1 && ~isempty(app.EMGChannels)
                    app.EMGLH = app.initializePlots(app.EMGAxes, app.EMGChannels+1, app.EMGChannelNames);
                end
                
            else
                % If no device is selected
                uialert(app.TargetDAQUI,'Select a valid DAQ Device from the dropdown menu.','No DAQ Device Selected')
            end   
                 
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % disable/enable Buttons
            app.EnableDisableInitialization(1);
            
            % stop and clear DAQ session
            if ~isempty(app.DAQSession)
                stop(app.DAQSession);
                app.deleteHandle(app.DAQSession);
            end            
            
            % clear line handles to remove feedback 
            if ishandle(app.FigureHandle) % target window is open
               app.FBPyramidLH = app.clearLine(app.FBPyramidLH);
            end
            
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            if ~isempty(app.subjID)
                
                setup = app.setupStruct();
                
                if ~isempty(app.datadir)
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.datadir '\' app.subjID '_Setup.mat']);
                else 
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.subjID '_Setup.mat']);
                end
                
                if fname ~= 0
                    save(fullfile(pname,fname),'setup');
                else
                    warndlg('Setup file was not saved','Save Setup')
                end
                
            else
                warndlg('Please fill the participant information form first','Save Setup');
            end           
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            
            if ~isempty(app.datadir)
                [fname,pname] = uigetfile([app.datadir '\*.mat'], 'Select the setup file (*.mat)');
            else 
                [fname,pname] = uigetfile([pwd '\*.mat'], 'Select the setup file (*.mat)');
            end
            
            if fname ~= 0 
                load(fullfile(pname,fname), 'setup');
                if exist('setup')
                    app.loadConfigUpdate(setup);                           
                end
            end
        end

        % Value changed function: ParticipantIDField
        function ParticipantIDFieldValueChanged(app, event)
            app.subjID = app.ParticipantIDField.Value;            
        end

        % Value changed function: ProtocolField
        function ProtocolFieldValueChanged(app, event)
            app.Protocol = app.ProtocolField.Value;            
        end

        % Value changed function: NotesField
        function NotesFieldValueChanged(app, event)
            app.Notes = app.NotesField.Value;            
        end

        % Value changed function: scanRateField
        function scanRateFieldValueChanged(app, event)
            app.sRate = app.scanRateField.Value;            
        end

        % Value changed function: sampleTimeField
        function sampleTimeFieldValueChanged(app, event)
            app.sTime = app.sampleTimeField.Value;            
        end

        % Value changed function: CollectEMGCheckBox
        function CollectEMGCheckBoxValueChanged(app, event)
            collectEMGbool = app.CollectEMGCheckBox.Value;
            
            % save state into property
            app.collectEMG = collectEMGbool;
            
            switch collectEMGbool
                
                case 0 % unchecked
                    
                    % stop collecting EMG
                    app.nEMGSpinner.Value = 0;
                    app.nEMGSpinner.Enable = 'off';
                    app.EMGChannels = [];
                    
                case 1 % checked
                    
                    % start collecting EMG signals                    
                    app.nEMGSpinner.Enable = 'on';
                    
            end
            
            % run value change callback
            app.nEMGSpinnerValueChanged()                
            
        end

        % Value changed function: nEMGSpinner
        function nEMGSpinnerValueChanged(app, event)
            
            % number of EMG channels to collect
            nEMG = app.nEMGSpinner.Value;           
            
            % set EMGChannels property to correct channels and initialize plots 
            if (nEMG > 0) && (app.collectEMG == 1)
                app.EMGChannels = app.EMGChannelsAvail(1:nEMG);                  
            else % nEMG == 0
                app.EMGChannels = [];                
            end 
            
            % delete previous line handles 
            app.EMGLH = app.deleteHandle(app.EMGLH);
            
            % update EMG axes and line handles
            app.EMGLH = app.initializePlots(app.EMGAxes, app.EMGChannels+1, app.EMGChannelNames);
            
        end

        % Close request function: TargetDAQUI
        function TargetDAQUICloseRequest(app, event)

            if ~isempty(app.DAQSession)
                if app.DAQSession.IsRunning
                    acquiring = 1;
                else
                    acquiring = 0;
                end
            else
                acquiring = 0;
            end
            
            if acquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.TargetDAQUI, question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,acquiring));                                  
            
        end

        % Value changed function: DAQDeviceDropDown
        function DAQDeviceDropDownValueChanged(app, event)
            
            app.daqDevice = app.DAQDeviceDropDown.Value - 1;                           

        end

        % Value changed function: ShowTargetCheckBox
        function ShowTargetCheckBoxValueChanged(app, event)
            % get current value 
            app.drawtarget = app.ShowTargetCheckBox.Value;
            
            if ishandle(app.FigureHandle) % target feedback window open
                switch app.drawtarget                    
                    case 0 % unchecked: don't show target
                        app.TPyramidLH = app.deleteHandle(app.TPyramidLH);
                        app.TargetTraceLH = app.deleteHandle(app.TargetTraceLH);
                    case 1 % checked: show target
                        app.updateTarget(app.TargetMode);
                end                
            end

           
        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
           
            % disable buttons and update status for acquisition
            app.EnableDisableAcquisition(0)
            
            % unique temporary file name and open handle to file for saving 
            app.TempFilename = tempname;
            app.TempFile = fopen(app.TempFilename, 'w');                   
            
            % set logdata flag to 1 to start saving data to file in data available callback function 
            app.logdata = 1;
            
        end

        % Button pushed function: CurrentDirectoryButton
        function CurrentDirectoryButtonPushed(app, event)
            app.datadir = uigetdir(cd, 'Select Directory to Save to');
            app.CurrentDirectoryTextArea.Value = app.datadir;
        end

        % Value changed function: TrialNumberField
        function TrialNumberFieldValueChanged(app, event)
            app.itrial = app.TrialNumberField.Value;            
        end

        % Cell edit callback: WrenchLimitsTable
        function WRangeValueChanged(app, event)
            app.WRanges = app.WrenchLimitsTable.Data;

            app.updateTarget(app.TargetMode);

        end

        % Cell edit callback: TargetAxesLimitsTable
        function TargetAxesLimitsTableCellEdit(app, event)
            newlim = app.TargetAxesLimitsTable.Data;
            app.VisLim = [newlim(1:3,:); deg2rad(newlim(4:6,:))]; 
            
            
            if all([~isempty(app.FigureHandle) ~isempty(app.AxesHandle)]) 
                axlims = app.adjustAxesLimits(app.VisLim, app.pyramid_width0, 2*app.pyramid_width0);
                set(app.AxesHandle(1), 'XLim', axlims(1,:), 'YLim', axlims(2,:), 'ZLim', axlims(3,:));
            end

            app.updateTarget(app.TargetMode);

        end

        % Cell edit callback: HeadFrameTable
        function HeadFrameTableCellEdit(app, event)
        
            % get location and orientation of head frame from ui table
            d = app.HeadFrameTable.Data;
            th = deg2rad(d(4:6)); % (deg -> rad)
            p = d(1:3); % (m)
            
            % adjoint matrix to transform between coordinate frames
            app.LC2Head = app.computeCoordinateTransform(p,th);            
            
        end

        % Value changed function: TargetModeDropDown
        function TargetModeDropDownValueChanged(app, event)
            app.TargetMode = str2num(app.TargetModeDropDown.Value);
            
        end

        % Value changed function: WrenchFrameDropDown
        function WrenchFrameDropDownValueChanged(app, event)
            app.FBFrameMode = str2num(app.WrenchFrameDropDown.Value);            
            
            % update LC2Head transform 
            app.HeadFrameTableCellEdit()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetDAQUI = uifigure;
            app.TargetDAQUI.Name = 'UI Figure';
            app.TargetDAQUI.CloseRequestFcn = createCallbackFcn(app, @TargetDAQUICloseRequest, true);
            app.TabGroup = uitabgroup(app.TargetDAQUI);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DAQConfigurationPanel = uipanel(app.SetupTab);
            app.DAQConfigurationPanel.Title = 'DAQ Configuration';
            app.DAQDeviceDropDownLabel = uilabel(app.DAQConfigurationPanel);
            app.DAQDeviceDropDownLabel.Text = 'DAQ Device';
            app.DAQDeviceDropDown = uidropdown(app.DAQConfigurationPanel);
            app.DAQDeviceDropDown.Items = {'Select a Device'};
            app.DAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQDeviceDropDownValueChanged, true);
            app.DAQDeviceDropDown.Value = 'Select a Device';
            app.ScanRateLabel = uilabel(app.DAQConfigurationPanel);
            app.ScanRateLabel.Text = 'Scan Rate';
            app.scanRateField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.scanRateField.Limits = [0 Inf];
            app.scanRateField.ValueChangedFcn = createCallbackFcn(app, @scanRateFieldValueChanged, true);
            app.scanRateField.Value = 1000;
            app.SampleTimeLabel = uilabel(app.DAQConfigurationPanel);
            app.SampleTimeLabel.Text = 'Sample Time';
            app.sampleTimeField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.sampleTimeField.Limits = [0 Inf];
            app.sampleTimeField.ValueChangedFcn = createCallbackFcn(app, @sampleTimeFieldValueChanged, true);
            app.sampleTimeField.Value = 5;
            app.SubjectInformationPanel = uipanel(app.SetupTab);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.ParticipantIDEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ParticipantIDField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDFieldValueChanged, true);
            app.ProtocolEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ProtocolEditFieldLabel.Text = 'Protocol';
            app.ProtocolField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ProtocolField.ValueChangedFcn = createCallbackFcn(app, @ProtocolFieldValueChanged, true);
            app.NotesTextAreaLabel = uilabel(app.SubjectInformationPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesField = uitextarea(app.SubjectInformationPanel);
            app.NotesField.ValueChangedFcn = createCallbackFcn(app, @NotesFieldValueChanged, true);
            app.SaveConfigButton = uibutton(app.SetupTab, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.SetupTab, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.EMGOptionsPanel = uipanel(app.SetupTab);
            app.EMGOptionsPanel.Title = 'EMG Options';
            app.CollectEMGCheckBox = uicheckbox(app.EMGOptionsPanel);
            app.CollectEMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollectEMGCheckBoxValueChanged, true);
            app.CollectEMGCheckBox.Text = 'Collect EMG';
            app.ofEMGsSpinnerLabel = uilabel(app.EMGOptionsPanel);
            app.ofEMGsSpinnerLabel.Enable = 'off';
            app.ofEMGsSpinnerLabel.Text = '# of EMGs';
            app.nEMGSpinner = uispinner(app.EMGOptionsPanel);
            app.nEMGSpinner.Limits = [0 6];
            app.nEMGSpinner.ValueChangedFcn = createCallbackFcn(app, @nEMGSpinnerValueChanged, true);
            app.nEMGSpinner.Enable = 'off';
            app.InitializeDAQButton = uibutton(app.SetupTab, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.FileOptionsPanel = uipanel(app.SetupTab);
            app.FileOptionsPanel.Title = 'File Options';
            app.CurrentDirectoryButton = uibutton(app.FileOptionsPanel, 'push');
            app.CurrentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentDirectoryButtonPushed, true);
            app.CurrentDirectoryButton.Text = {'Current '; 'Directory'};
            app.CurrentDirectoryTextArea = uitextarea(app.FileOptionsPanel);
            app.basefilenameLabel = uilabel(app.FileOptionsPanel);
            app.basefilenameLabel.Text = 'base filename';
            app.basefilenameEditField = uieditfield(app.FileOptionsPanel, 'text');
            app.basefilenameEditField.Value = 'data_trial';
            app.TransformTab = uitab(app.TabGroup);
            app.TransformTab.Title = 'Transform';
            app.BonyLandmarkTableLabel = uilabel(app.TransformTab);
            app.BonyLandmarkTableLabel.Text = 'Head Frame (in World Frame)';
            app.HeadFrameTable = uitable(app.TransformTab);
            app.HeadFrameTable.ColumnName = {''};
            app.HeadFrameTable.RowName = {'X (m)'; 'Y (m)'; 'Z (m)'; 'thX (deg)'; 'thY (deg)'; 'thZ (deg)'};
            app.HeadFrameTable.ColumnEditable = true;
            app.HeadFrameTable.CellEditCallback = createCallbackFcn(app, @HeadFrameTableCellEdit, true);
            app.XForwardYVerticalupZRightLabel = uilabel(app.TransformTab);
            app.XForwardYVerticalupZRightLabel.Text = {'X(+):  Forward'; 'Y(+):  Vertical (up)'; 'Z(+):  Right '};
            app.WrenchFrameDropDownLabel = uilabel(app.TransformTab);
            app.WrenchFrameDropDownLabel.Text = 'Wrench Frame';
            app.WrenchFrameDropDown = uidropdown(app.TransformTab);
            app.WrenchFrameDropDown.Items = {'Head', 'World', 'LoadCell'};
            app.WrenchFrameDropDown.ItemsData = {'1', '2', '3'};
            app.WrenchFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @WrenchFrameDropDownValueChanged, true);
            app.WrenchFrameDropDown.Value = '1';
            app.SetTargetTab = uitab(app.TabGroup);
            app.SetTargetTab.Title = 'Set Target';
            app.TargetSettingsPanel = uipanel(app.SetTargetTab);
            app.TargetSettingsPanel.Title = 'Target Settings ';
            app.TargetDirectionLabel = uilabel(app.TargetSettingsPanel);
            app.TargetDirectionLabel.Text = 'Target Direction';
            app.TargetTable = uitable(app.TargetSettingsPanel);
            app.TargetTable.ColumnName = {'Target'};
            app.TargetTable.RowName = {'Fx (N)'; 'Fy (N)'; 'Fz (N)'; 'Mx (Nm)'; 'My (Nm)'; 'Mz (Nm)'};
            app.TargetTable.ColumnEditable = true;
            app.TargetTable.CellEditCallback = createCallbackFcn(app, @TargetSetValueChanged, true);
            app.MagnitudeSliderLabel = uilabel(app.TargetSettingsPanel);
            app.MagnitudeSliderLabel.Text = 'Magnitude';
            app.MagnitudeSlider = uislider(app.TargetSettingsPanel);
            app.OpenTargetWindowButton = uibutton(app.SetTargetTab, 'state');
            app.OpenTargetWindowButton.ValueChangedFcn = createCallbackFcn(app, @OpenTargetWindowButtonValueChanged, true);
            app.OpenTargetWindowButton.Text = 'Open Target Window';
            app.ZeroLoadCellButton = uibutton(app.SetTargetTab, 'push');
            app.ZeroLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroLoadCellButtonPushed, true);
            app.ZeroLoadCellButton.Text = 'Zero Load Cell';
            app.ShowTargetCheckBox = uicheckbox(app.SetTargetTab);
            app.ShowTargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTargetCheckBoxValueChanged, true);
            app.ShowTargetCheckBox.Enable = 'off';
            app.ShowTargetCheckBox.Text = 'Show Target';
            app.RangesPanel = uipanel(app.SetTargetTab);
            app.RangesPanel.Title = 'Ranges';
            app.WrenchLimitsTable = uitable(app.RangesPanel);
            app.WrenchLimitsTable.ColumnName = {'low'; 'high'};
            app.WrenchLimitsTable.RowName = {'Fx (N)'; 'Fy (N)'; 'Fz (N)'; 'Mx (Nm)'; 'My (Nm)'; 'Mz (Nm)'};
            app.WrenchLimitsTable.ColumnEditable = true;
            app.WrenchLimitsTable.CellEditCallback = createCallbackFcn(app, @WRangeValueChanged, true);
            app.WrenchRangeLabel = uilabel(app.RangesPanel);
            app.WrenchRangeLabel.Text = 'Wrench Range';
            app.TargetAxesLimitsTable = uitable(app.RangesPanel);
            app.TargetAxesLimitsTable.ColumnName = {'low'; 'high'};
            app.TargetAxesLimitsTable.RowName = {'X (scaler)'; 'Y (pixels)'; 'Z (pixels)'; 'TX (deg)'; 'TY (deg)'; 'TZ (deg)'};
            app.TargetAxesLimitsTable.ColumnEditable = true;
            app.TargetAxesLimitsTable.CellEditCallback = createCallbackFcn(app, @TargetAxesLimitsTableCellEdit, true);
            app.VisualRangeLabel = uilabel(app.RangesPanel);
            app.VisualRangeLabel.Text = 'Visual Range';
            app.TargetModeDropDownLabel = uilabel(app.SetTargetTab);
            app.TargetModeDropDownLabel.Text = 'Target Mode';
            app.TargetModeDropDown = uidropdown(app.SetTargetTab);
            app.TargetModeDropDown.Items = {'Match', 'Dir and Mag', 'Trace', 'Side by Side'};
            app.TargetModeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TargetModeDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetModeDropDownValueChanged, true);
            app.TargetModeDropDown.Value = '1';
            app.WrenchPlotsTab = uitab(app.TabGroup);
            app.WrenchPlotsTab.Title = 'WrenchPlots';
            app.WrenchAxes1 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes2 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes3 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes4 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes5 = uiaxes(app.WrenchPlotsTab);
            app.WrenchAxes6 = uiaxes(app.WrenchPlotsTab);
            app.EMGPlotsTab = uitab(app.TabGroup);
            app.EMGPlotsTab.Title = 'EMGPlots';
            app.EMGAxes1 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes2 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes3 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes4 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes5 = uiaxes(app.EMGPlotsTab);
            app.EMGAxes6 = uiaxes(app.EMGPlotsTab);
            app.StopButton = uibutton(app.TargetDAQUI, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.TrialEditFieldLabel = uilabel(app.TargetDAQUI);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialNumberField = uieditfield(app.TargetDAQUI, 'numeric');
            app.TrialNumberField.Limits = [1 Inf];
            app.TrialNumberField.RoundFractionalValues = 'on';
            app.TrialNumberField.ValueDisplayFormat = '%.0f';
            app.TrialNumberField.ValueChangedFcn = createCallbackFcn(app, @TrialNumberFieldValueChanged, true);
            app.TrialNumberField.Value = 1;
            app.StatusLabel = uilabel(app.TargetDAQUI);
            app.StatusLabel.Text = 'Status: ';
            app.AcquireDataButton = uibutton(app.TargetDAQUI, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tDAQpy0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetDAQUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetDAQUI)
        end
    end
end